classdef LUTapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenoptfileMenu                 matlab.ui.container.Menu
        OpenoptefileMenu                matlab.ui.container.Menu
        MergewithMenu                   matlab.ui.container.Menu
        OpenpowersourceonlyfileMenu     matlab.ui.container.Menu
        OpenpowerfileMenu               matlab.ui.container.Menu
        frequencyRespnseButtonGroup     matlab.ui.container.ButtonGroup
        Weight01EditField               matlab.ui.control.NumericEditField
        Weight01EditFieldLabel          matlab.ui.control.Label
        meaneffoptimCheckBox            matlab.ui.control.CheckBox
        CSTResultsPanel                 matlab.ui.container.Panel
        CSTresultsfilenameLabel         matlab.ui.control.Label
        LowerdistEditField              matlab.ui.control.NumericEditField
        LowerdistEditFieldLabel         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        UpperdistEditField              matlab.ui.control.NumericEditField
        UpperdistEditFieldLabel         matlab.ui.control.Label
        CSTresultsfilenameEditField     matlab.ui.control.EditField
        freqRespGoButton                matlab.ui.control.Button
        AlloptfileentriesCheckBox       matlab.ui.control.CheckBox
        numberofsamplesEditField        matlab.ui.control.NumericEditField
        numberofsamplesEditFieldLabel   matlab.ui.control.Label
        refefficiencyEditField          matlab.ui.control.NumericEditField
        refefficiencyLabel              matlab.ui.control.Label
        maxfreqGHzEditField             matlab.ui.control.NumericEditField
        maxfreqGHzEditFieldLabel        matlab.ui.control.Label
        minfreqGHzEditField             matlab.ui.control.NumericEditField
        minfreqGHzEditFieldLabel        matlab.ui.control.Label
        focdistnumedit                  matlab.ui.container.ButtonGroup
        EraseunitcellsButton            matlab.ui.control.Button
        unitcellsCheckBox               matlab.ui.control.CheckBox
        focdistEditField                matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        AnalyzeArbPhaseButton           matlab.ui.control.Button
        optMeanEff                      matlab.ui.control.Label
        optarbphase                     matlab.ui.control.Label
        producesmeaneffLabel            matlab.ui.control.Label
        optimumarbphaseLabel            matlab.ui.control.Label
        etaSidewaysLabel                matlab.ui.control.Label
        etaRLabel                       matlab.ui.control.Label
        etaTLabel                       matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        RLabel                          matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        TLabel                          matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        lensThicknessLabel              matlab.ui.control.Label
        thicknessmmLabel                matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        powerfilenameLabel              matlab.ui.control.Label
        powersourcefilenameLabel        matlab.ui.control.Label
        NounitcellslensEditField        matlab.ui.control.NumericEditField
        NounitcellslensEditFieldLabel   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        lambdaLabel                     matlab.ui.control.Label
        periodmmEditField               matlab.ui.control.NumericEditField
        periodmmEditFieldLabel          matlab.ui.control.Label
        freqGHzEditField                matlab.ui.control.NumericEditField
        freqGHzLabel                    matlab.ui.control.Label
        nomagsSpinner                   matlab.ui.control.Spinner
        noanglesSpinnerLabel_2          matlab.ui.control.Label
        targetmag2EditField             matlab.ui.control.NumericEditField
        targetmagEditFieldLabel_2       matlab.ui.control.Label
        DisplayButtonGroup              matlab.ui.container.ButtonGroup
        meanEfficiencyRadioButton       matlab.ui.control.RadioButton
        RecalcWkCheckBox                matlab.ui.control.CheckBox
        nonsymindexEditField            matlab.ui.control.NumericEditField
        nonsymindexEditFieldLabel       matlab.ui.control.Label
        ExhaustivefilterButtonGroup     matlab.ui.container.ButtonGroup
        exhMaxMagNumEdit                matlab.ui.control.NumericEditField
        nLargestSpinner                 matlab.ui.control.Spinner
        KeepthelargestLabel             matlab.ui.control.Label
        exhMinMagNumEdit                matlab.ui.control.NumericEditField
        exhPhaseDiffNumEdit             matlab.ui.control.NumericEditField
        fieldmagLabel                   matlab.ui.control.Label
        degsLabel                       matlab.ui.control.Label
        EveryLabel                      matlab.ui.control.Label
        exhGoMagnitudeFilterPushbutton  matlab.ui.control.Button
        exhGoPhaseFilterPushbutton      matlab.ui.control.Button
        phsValue                        matlab.ui.control.Label
        magValue                        matlab.ui.control.Label
        PhsLabel                        matlab.ui.control.Label
        MagLabel                        matlab.ui.control.Label
        sortbyphaseButtonGroup          matlab.ui.container.ButtonGroup
        GoSortButton                    matlab.ui.control.Button
        CSTButton                       matlab.ui.control.RadioButton
        targetButton                    matlab.ui.control.RadioButton
        CombinewithFilesButton          matlab.ui.control.Button
        closeallfiguresButton           matlab.ui.control.Button
        savemodifiedfilesButton         matlab.ui.control.Button
        DeletepointButton               matlab.ui.control.Button
        ResultNoSpinner                 matlab.ui.control.Spinner
        ResultNoSpinnerLabel            matlab.ui.control.Label
        displayCSTCheckBox              matlab.ui.control.CheckBox
        displayMatlabCheckBox           matlab.ui.control.CheckBox
        DeltaMatlabCSTButton            matlab.ui.control.Button
        AddCSTButton                    matlab.ui.control.Button
        optFileLabel                    matlab.ui.control.Label
        ChoosePointsButton              matlab.ui.control.Button
        DisplayLUTButton                matlab.ui.control.Button
        efficiencyRadioButton           matlab.ui.control.RadioButton
        fieldRadioButton                matlab.ui.control.RadioButton
        OkButton                        matlab.ui.control.Button
        LUTparametersLabel              matlab.ui.control.Label
        targetmagEditField              matlab.ui.control.NumericEditField
        targetmagEditFieldLabel         matlab.ui.control.Label
        mineffEditField                 matlab.ui.control.NumericEditField
        mineffEditFieldLabel            matlab.ui.control.Label
        angle2EditField                 matlab.ui.control.NumericEditField
        angle2EditFieldLabel            matlab.ui.control.Label
        angle1EditField                 matlab.ui.control.NumericEditField
        angle1EditFieldLabel            matlab.ui.control.Label
        noanglesSpinner                 matlab.ui.control.Spinner
        noanglesSpinnerLabel            matlab.ui.control.Label
        maxtimesSpinner                 matlab.ui.control.Spinner
        maxtimesSpinnerLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % Main app
        MainHandles %Handles structure of the main app.
        % LUTmaxtimes %For tries using the multiple-times checkbox.
        % LUTminEff   %The minimum efficiency to be recorded.
        % NLUTangles  %The number of angles for which LUT will be created.
        % LUTang1     %The first phase angle of trans coeff T.
        % LUTang2     %The last phase angle of trans coeff T.
        % LUTtargetTMagnitude   %The target magnitude of trans. coeff. T.
        magLUTFields
        phsLUTFields
        magCSTFields
        phsCSTFields
        h
        hCST
        WkLUT
        imLUT
        reLUT
        LUTresults
        hCartes
        fileNameTTCST
        magCSTFieldsReflec
        MatlabMark
        CSTMark
        reCST
        imCST
        MatlabMarkMag
        MatlabMarkPhs
        hh
        cartesMarker
        widthMarker
        lambdamm
        dmm
        unitCellDots
        LUTAxis
        arbPhaseMarker
        unitCellCircles
        meanEff
        meanEffCST
        hCartesCST
        deletedPoints
        numWkLayers
    end
        
    methods (Access = private)
        
        function readOptFile(app, LUTFileName,ise)
            %ise indicates whether the file also contains the
            %meanEfficiency over the frequency band. 0 it doesn't, 1 it
            %does.
            app.deletedPoints=[];
            app.LUTresults=load(LUTFileName,'-ascii');
            app.magLUTFields=app.LUTresults(:,2);
            if ise
                app.meanEfficiencyRadioButton.Value=1;
                magLUT=app.LUTresults(:,5);
                app.meanEff=magLUT;
                app.WkLUT=app.LUTresults(:,6:end);
            else
                app.efficiencyRadioButton.Value=1;
                magLUT=app.magLUTFields;
                if app.efficiencyRadioButton.Value
                    magLUT=magLUT.*magLUT;
                end
                app.WkLUT=app.LUTresults(:,5:end);
            end
            sizeLUT=size(app.WkLUT);
            app.numWkLayers=sizeLUT(2);
            phsLUT=app.LUTresults(:,3);
            app.phsLUTFields=phsLUT;
            app.reLUT=magLUT.*cosd(phsLUT);
            app.imLUT=magLUT.*sind(phsLUT);
            %plot magnitude and phase results
            figure
            set(gcf,'CloseRequestFcn','app.ChoosePointsButton.Enable=''off''; closereq')
            %Setting Enable to off evidently doesn't work, because the
            %figure has no way of knowing what "app" is.
            app.h=plot(app.reLUT,app.imLUT,'.');
            app.h.MarkerSize=12;
            app.h.Color='b';
            fmin=app.minfreqGHzEditField.Value;
            fmax=app.maxfreqGHzEditField.Value;
            if ise
                title(['Mean Efficiencies for frequencies between ' ...
                    num2str(fmin) ' and ' num2str(fmax) 'GHz'])
            else
                if app.efficiencyRadioButton.Value
                    title('Transmitted Efficiencies')
                else
                    title('Transmitted Fields')
                end
            end
            %draw unit circle. Saw this at
            %  https://www.mathworks.com/matlabcentral/answers/98665-how-do-i-plot-a-circle-with-a-given-radius-and-center
            hold on
            r=0.5:0.1:0.9;
            h1=fplot(@(t) sin(t), @(t) cos(t));
            h1.Color='r';
            for ii=1:length(r)
                hr(ii)=fplot(@(t) r(ii)*sin(t), @(t) r(ii)*cos(t));
                hr(ii).Color='b';
            end
            %Plot radials to indicate segments of 30 deg.
            ang=-180:30:150;
            for ii=1:length(ang)
                hang(ii)=line([0 sind(ang(ii))],[0,cosd(ang(ii))]);
                hang(ii).LineStyle='--';
                hang(ii).Color='k';
            end
            hold off
            xlabel('Re(T_0)')
            ylabel('Im(T_0)')
            grid
            axis('equal')
            app.LUTAxis=app.h.Parent;
            CartesPlots(app,ise);
        end
            %
        function CartesPlots(app,ise)
            %Plot results on Cartesian axes. We want this to be efficiency
            %no matter what.
            if ise
                magLUT=app.LUTresults(:,5);
            else
                magLUT=app.LUTresults(:,2);
                magLUT=magLUT.*magLUT;
            end
            %Temporary, until we change LUT to include the reflected wave
            %as well.
            phsRef=app.LUTresults(:,1);
            absR0=app.LUTresults(:,4);
            %phsRef=-180:2:178;
            %RT=importdata('RT.ext');
            %absR0=RT(:,3);
            absR0=absR0.*absR0; %We are interested here in efficiency
            absorbed=1-magLUT-absR0;
            if ishandle(app.hCartes)
                %The graphic already exists.  So update its contents.
                app.hCartes(1).XData=phsRef;
                app.hCartes(2).XData=phsRef;
                app.hCartes(3).XData=phsRef;
                app.hCartes(1).YData=magLUT*100;
                app.hCartes(2).YData=absR0*100;
                app.hCartes(3).YData=absorbed*100;
                phsRefa=app.LUTresults(1,1);
                app.cartesMarker.XData=[phsRefa phsRefa];
            else
                figure
                app.hCartes=plot(phsRef,magLUT*100,'--b',phsRef,absR0*100,'--r',...
                    phsRef,absorbed*100,'--g','LineWidth',2);
                CartesAxis=app.hCartes.Parent;
                CartesAxis.XLim=[-180 180];
                CartesAxis.YLim=[0 100];
                xlabel('phase (deg)')
                ylabel('Efficiency (%)')
                grid
                axCartes=app.hCartes.Parent;
                phsRefa=app.LUTresults(1,1);
                app.cartesMarker=line(axCartes,[phsRefa phsRefa],[0 100],'color','k');
                legend(app.hCartes,'ML Transmission','ML Reflection','ML Absorbed')
            end
            %
            %Plot the lengths of the plates as a function of the phase
            %angle.
            %Determine whether there is symmetry. I.e. 1=5, 2=4 if 5 layers.
            halfNumWkLayers=fix(app.numWkLayers/2);
            nLayersToVary=halfNumWkLayers;
            if 2*halfNumWkLayers~=app.numWkLayers
                nLayersToVary=halfNumWkLayers+1;
            end
            isSym=1;   %There is symmetry.
            for ii=1:halfNumWkLayers
                if ~all(app.WkLUT(:,ii)==app.WkLUT(:,app.numWkLayers+1-ii))
                    isSym=0;
                    break
                end
            end
            % if all(app.WkLUT(:,1)==app.WkLUT(:,5)) && all(app.WkLUT(:,2)==app.WkLUT(:,4))
            %     isSym=1;
            % else
            %     isSym=0;
            % end
            %In case there is now non symmetry but in the previous geometry
            %there was symmetry, then app.hh(4) and app.hh(5) are not
            %defined. In that case we must start the figure from scratch.
            if ishandle(app.hh)
                %if ~isSym && length(app.hh)<4
                if ~isSym && length(app.hh)<app.numWkLayers
                    %Identify the figure and lcose it
                    hhfig=app.hh(1).Parent.Parent;
                    close(hhfig);
                end
            end
            %
            %Define array of markers and colors for use in plot.
            markCol=['sb';'xr';'og';'+c';'^m';'sr';'xg';'oc';'+m';'^b';...
                'sg';'xc';'om';'+b';'^r';'sc';'xm';'ob';'+r';'^g';];
            WkStr=['Wk1 ';'Wk2 ';'Wk3 ';'Wk4 ';'Wk5 ';'Wk6 ';'Wk7 ';'Wk8 ';'Wk9 ';...
                'Wk10';'Wk11';'Wk12';'Wk13';'Wk14';'Wk15';'Wk16';'Wk17';'Wk18';'Wk19';...
                'Wk20';];
            %Thes only go up to 20, but we shouldn't need so many.
            if isSym
                if ishandle(app.hh)
                    %The graphic already exists.  So update its contents.
                    for ii=1:nLayersToVary
                        app.hh(ii).XData=phsRef;
                        app.hh(ii).YData=app.WkLUT(:,ii);
                    end
                    % app.hh(1).XData=phsRef;
                    % app.hh(2).XData=phsRef;
                    % app.hh(3).XData=phsRef;
                    % app.hh(1).YData=app.WkLUT(:,1);
                    % app.hh(2).YData=app.WkLUT(:,2);
                    % app.hh(3).YData=app.WkLUT(:,3);
                    phsRefa=app.LUTresults(1,1);
                    app.widthMarker.XData=[phsRefa phsRefa];
                else
                    figure
                    app.hh=gobjects(nLayersToVary,1);
                    hold on
                    for ii=1:nLayersToVary
                        app.hh(ii)=plot(phsRef,app.WkLUT(:,ii),markCol(ii));
                    end
                    hold off
                    % app.hh=plot(phsRef,app.WkLUT(:,1),'sb',phsRef,app.WkLUT(:,2),'xr',...
                    %     phsRef,app.WkLUT(:,3),'og');
                    ax=app.hh.Parent;
                    ax.XLim=[-180 180];
                    %ax.YLim=[0 120];
                    xlabel('phase (deg)')
                    ylabel('Width (mil)')
                    grid
                    axhh=app.hh.Parent;
                    ylim=get(axhh,'YLim');
                    app.widthMarker=line(axhh,[phsRefa phsRefa],ylim,'color','k');
                    legend(app.hh,WkStr(1:nLayersToVary,:))
                    %legend(app.hh,'Wk1','Wk2','Wk3')
                end
            else
                if ishandle(app.hh)
                    %The graphic already exists.  So update its contents.
                    for ii=1:app.numWkLayers
                        app.hh(ii).XData=phsRef;
                        app.hh(ii).YData=app.WkLUT(:,ii);
                    end
                    % app.hh(1).XData=phsRef;
                    % app.hh(2).XData=phsRef;
                    % app.hh(3).XData=phsRef;
                    % app.hh(4).XData=phsRef;
                    % app.hh(5).XData=phsRef;
                    % app.hh(1).YData=app.WkLUT(:,1);
                    % app.hh(2).YData=app.WkLUT(:,2);
                    % app.hh(3).YData=app.WkLUT(:,3);
                    % app.hh(4).YData=app.WkLUT(:,4);
                    % app.hh(5).YData=app.WkLUT(:,5);
                    phsRefa=app.LUTresults(1,1);
                    app.widthMarker.XData=[phsRefa phsRefa];
                else
                    figure
                    app.hh=gobjects(app.numWkLayers,1);
                    hold on
                    for ii=1:app.numWkLayers
                        app.hh(ii)=plot(phsRef,app.WkLUT(:,ii),markCol(ii));
                    end
                    hold off
                    % app.hh=plot(phsRef,app.WkLUT(:,1),'sb',phsRef,app.WkLUT(:,2),'xr',...
                    %     phsRef,app.WkLUT(:,3),'og',phsRef,app.WkLUT(:,4),'+c',...
                    %     phsRef,app.WkLUT(:,5),'^m');
                    ax=app.hh.Parent;
                    ax.XLim=[-180 180];
                    %ax.YLim=[0 120];
                    xlabel('phase (deg)')
                    ylabel('Width (mil)')
                    grid
                    axhh=app.hh.Parent;
                    ylim=get(axhh,'YLim');
                    app.widthMarker=line(axhh,[phsRefa phsRefa],ylim,'color','k');
                    legend(app.hh,WkStr(1:app.numWkLayers,:))
                    %legend(app.hh,'Wk1','Wk2','Wk3','Wk4','Wk5')
                end
            end  %isSym
        end

        function readCSTfile(app,FileName,ise)
            CSTPolarData=importdata(FileName);
            try
                CSTData=CSTPolarData.data;
                %This is necessary for a file that came from CST since
                %there is a header of a few lines.
            catch
                %This is the format when we create the file instead of
                %CST, since here there is no header.
                CSTData=CSTPolarData;
            end
            %3 columns: Run no., Magnitude, Phase 
            app.magCSTFields=CSTData(:,2);
            app.phsCSTFields=-CSTData(:,3);
            if ise
                app.meanEffCST=CSTData(:,4);
            end
            %NOTE NOTE NOTE The negative sign is because this is a CST
            %file which is based on exp(jwt), while we are using
            %exp(-iwt);
            magCST=app.magCSTFields;
            phsCST=app.phsCSTFields;
            if app.efficiencyRadioButton.Value
                magCST=magCST.*magCST;
            end
            if app.meanEfficiencyRadioButton.Value
                magCST=app.meanEffCST;
            end
            app.reCST=magCST.*cosd(phsCST);
            app.imCST=magCST.*sind(phsCST);
            %Get axis to plot on.
            if ishandle(app.h)
                currentAxis=app.h.Parent;
                axes(currentAxis);  %Make this axis current 
                hold on
                app.hCST=plot(app.reCST,app.imCST,'.');
                hold off
                app.hCST.MarkerSize=12;
                app.hCST.Color='r';
                %Place marker on the result the number of which is in the spinner.
                nconfig=app.ResultNoSpinner.Value;
                app.CSTMark=line(currentAxis,app.reCST(nconfig),app.imCST(nconfig),...
                    'Marker','square','MarkerEdgeColor','red','MarkerSize',16,...
                    'LineWidth',2);
                legend([app.h,app.hCST],'Matlab','CST')
                app.DeltaMatlabCSTButton.Enable='on';
                %
            else
                msgbox('No existing figure. First display LUT')
            end
            %
            %Also plot on Cartesian axis. This one is always
            %efficiency.
            %phsRef=-180:2:178;
            %phsRef=app.phsLUTFields;
            phsRef=app.LUTresults(:,1);
            if app.fieldRadioButton.Value
                magCST=magCST.*magCST;
            end%
            %
            %This is temporary to find the reflected wave from a
            %different file.
            %I have an idea.  Require the file with the reflected wave
            %to have the same name as FileName, but with TT replaced by
            %RR.
            FileNameRR=replace(FileName,'TT','RR');
            RMag=importdata(FileNameRR);
            try
                RMagData=RMag.data;
            catch
                RMagData=RMag;
            end
            RR=RMagData(:,2);
            app.magCSTFieldsReflec=RR;
            RREff=RR.*RR;
            Absorbed=1-magCST-RREff;
            %
            currentAxisCartes=app.hCartes.Parent;
            axes(currentAxisCartes) %Make this axis curren
            hold on
            %temporarily changed. Changed back
             app.hCartesCST=plot(phsRef,magCST*100,'b',phsRef,RREff*100,'r',...
                 phsRef,Absorbed*100,'g','LineWidth',2);
            % plot(phsCST,magCST*100,'b',phsCST,RREff*100,'r',...
            %     phsCST,Absorbed*100,'g','LineWidth',2);
            hold off
            legend([app.hCartes;app.hCartesCST],'ML Transmission','ML Reflection','ML Absorbed',...
                'CST Transmission','CST Reflection','CST Absorbed')
        end

        function combineWithFile(app)
            %This function integrates the set of files for which the .opt
            %file is named newOptFileName together with the existing data.
            %The existing data is that used in the delete points functions.
            %Get the current selected index.
            if isempty(app.hCST)
                %if CST results have not yet been read, we can't use this.
                msgbox('This option is only available if the CST results have been read.')
                return
            end
            %nConfig=app.ResultNoSpinner.Value;
            %Get current length of the variables
            %varlen=length(app.magLUTFields);
            %
            %Save the existing data.  Then read in the new data, and
            %combine them.
            %
            %Save the existing data.
            LUTresultsA=app.LUTresults;
            WkLUTA=app.WkLUT;
            magLUTFieldsA=app.magLUTFields;
            phsLUTFieldsA=app.phsLUTFields;
            imLUTA=app.imLUT;
            reLUTA=app.reLUT;
            magCSTFieldsA=app.magCSTFields;
            magCSTFieldsA=app.magCSTFields;
            magCSTFieldsReflecA=app.magCSTFieldsReflec;
            phsCSTFieldsA=app.phsCSTFields;
            reCSTA=app.reCST;
            imCSTA=app.imCST;
            if strcmp(app.optFileLabel.Text(end),'e')
                ise=1;
                meanEffA=app.meanEff;
                meanEffCSTA=app.meanEffCST;
            else
                ise=0;
            end
            %
            %Read in the new data.
            close all        %Close all open figures.
            app.LUTresults=[];
            event=[];
            displayLUTcallback(app, event);   %
            if ~isempty(app.LUTresults)
                app.LUTresults=[LUTresultsA; app.LUTresults];
                app.WkLUT=[WkLUTA; app.WkLUT];
                app.magLUTFields=[magLUTFieldsA; app.magLUTFields];
                app.phsLUTFields=[phsLUTFieldsA; app.phsLUTFields];
                app.imLUT=[imLUTA; app.imLUT];
                app.reLUT=[reLUTA; app.reLUT];
                app.magCSTFields=[magCSTFieldsA; app.magCSTFields];
                app.phsCSTFields=[phsCSTFieldsA; app.phsCSTFields];
                app.magCSTFieldsReflec=[magCSTFieldsReflecA; app.magCSTFieldsReflec];
                app.reCST=[reCSTA; app.reCST];
                app.imCST=[imCSTA; app.imCST];
                if ise
                    app.meanEff=[meanEffA;app.meanEff];
                    app.meanEffCST=[meanEffCSTA;app.meanEffCST];
                end
                
                %Redisplay the Matlab points.
                app.h.XData=app.reLUT;
                app.h.YData=app.imLUT;
                if ishandle(app.hCST)
                    %Redisplay the CST points
                    app.hCST.XData=app.reCST;
                    app.hCST.YData=app.imCST;
                end
                %Modify the spinner limit
                varlen=length(app.magLUTFields);
                app.ResultNoSpinner.Limits=[1 varlen];
            end
        end


        function readMCFileHMS(app, MCFileName)
            %The columns of MCFileName are:
            % [Matlab effT(%)]  [Matlab phi deg]  [CST phi deg]  [CST effT(%)] [CST effR] [CST effLoss] [Matlab effR] [Matlab effLoss]
            MCresults=load(MCFileName,'-ascii');
            magMatlab=MCresults(:,1)/100;
            phsMatlab=MCresults(:,2);
            reMatlab=magMatlab.*cosd(phsMatlab);
            imMatlab=magMatlab.*sind(phsMatlab);
            magCST=MCresults(:,4)/100;
            phsCST=MCresults(:,3);
            reCST=magCST.*cosd(phsCST);
            imCST=magCST.*sind(phsCST);

            figure
            hMatlab=plot(reMatlab,imMatlab,'.');
            hold on
            hCST=plot(reCST,imCST,'.');
            hold off
            hMatlab.MarkerSize=12;
            hMatlab.Color='b';
            hCST.MarkerSize=12;
            hCST.Color='r';
            %draw unit circle. Saw this at
            %  https://www.mathworks.com/matlabcentral/answers/98665-how-do-i-plot-a-circle-with-a-given-radius-and-center
            hold on
            r=0.5:0.1:0.9;
            h1=fplot(@(t) sin(t), @(t) cos(t));
            h1.Color='r';
            for ii=1:length(r)
                hr(ii)=fplot(@(t) r(ii)*sin(t), @(t) r(ii)*cos(t));
                hr(ii).Color='b';
            end
            hold off
            xlabel('Re(T_0)')
            ylabel('Im(T_0)')
            grid
            axis('equal')
            title('Transmitted Efficiencies')
            legend([app.h,app.hCST],'Matlab','CST')
        end

        function nonSymmetryIndex(app)
            %This function obtains a "non-symmetry index" which would
            %hopefully indicate the amount of non-symmetry in the Wk. It is
            %assumed that usually the W1, W2, W3, W4 and W5 would be
            %symmetric in that W1=W5, W2=W4 (assuming there are 5 layers).
            %The nonSymmetryIndex over all phases will be defined as the
            %sum over all phases of abs(W1-W5)+abs(W2-W4) divided by twice
            %the number of phases. In other words, the average difference
            %in Wk of the eligible layers.
            sizeWkLUT=size(app.WkLUT);
            nphases=sizeWkLUT(1);
            nLayers=sizeWkLUT(2);
            nsymmetries=fix(nLayers/2);
            sumDifW=zeros(nphases,1);
            for ii=1:nsymmetries
                sumDifW=sumDifW+abs(app.WkLUT(:,ii)-app.WkLUT(:,nLayers+1-ii));
            end
            nonSymIndex=sum(sumDifW)/(nphases*nsymmetries);
            app.nonsymindexEditField.Value=nonSymIndex;
        end

        function CSTArraySweepFile(app,FileName,ise)
            %Create a file with the same name as FileName but with
            %suffix .wk that contains the Wk values for each case given
            %in mm instead of in mil (as the opt file is). These were
            %defined in readOptFile as app.WkLUT (in mil). This file
            %will be used in CST to consider each set separately.
            %If ise=0 (1), the FileName has a suffix .opt (.opte). The
            %.opte file includes mean efficiency over frequencies.
            %Aside: first find non-symmetry index (just for the heck of
            %it).
            nonSymmetryIndex(app);
            %Get the new file name:
            if ise
                wkFileName=replace(FileName,'.opte','.wk');
            else
                wkFileName=replace(FileName,'.opt','.wk');
            end
            mil2mm=0.0254;
            Wkmm=app.WkLUT*mil2mm;
            sizeWkmm=size(Wkmm);
            ncols=sizeWkmm(2);
            header=[];
            for ii=1:ncols
                header=[header ' Wk' num2str(ii) ' '];
            end
            fid=fopen(wkFileName,'w');
            fprintf(fid,'%s\r\n',header);
            fclose(fid);
            save(wkFileName,'Wkmm','-ascii','-append')
        end 

        function getArbPhi(app)
            %Get the magnitude and phase of the cells in the .lut file.
            [FileName,PathName]=uigetfile('*.lut','Select the lut file');
            if PathName==0
                return
            end
            LUTdata=importdata(FileName);
            %This lut file was formulated for CST. As such, we had to
            %change the sign of the phase, and then flip it so that the
            %angles would be in sorted order.  We must now, therefore,
            %reverse the process.
            LUTdata=flipud(LUTdata);
            phsCSTdeg=-LUTdata(:,1);  %Should be sorted.
            magCST=LUTdata(:,2);
            magCSTEff=magCST.*magCST;
            nPhases=length(phsCSTdeg);
            y0=app.focdistEditField.Value;
            nunitsx=app.NounitcellslensEditField.Value;
            f0=app.freqGHzEditField.Value;
            %Consider that each one of these phases can be the arbitrary
            %phase.
            meanMagCSTEff=zeros(nPhases,1);
            for ii=1:nPhases
                phiArb=phsCSTdeg(ii);
                indLens=xxx(app,y0,nunitsx,phsCSTdeg,phiArb);
                meanMagCSTEff(ii)=mean(magCSTEff(indLens));
            end
            %Get the "arbitrary" phase for which the mean transmission
            %magnitude is largest.
            [maxMeanMagCSTEff,iimax]=max(meanMagCSTEff);
            optPhiArb=phsCSTdeg(iimax);
            app.optarbphase.Text=num2str(optPhiArb);
            app.optMeanEff.Text=num2str(maxMeanMagCSTEff);
            figure
            plot(phsCSTdeg,100*meanMagCSTEff,'b','LineWidth',2);
            currentaxis=gca;
            xlabel('arbitrary phase (deg)');
            ylabel('mean trans. efficiency (%)')
            grid
            title(['Foc dist = ' num2str(y0) ', ' num2str(nunitsx) 'cells/lens'])
            legend(['single freq ' num2str(f0) ' GHz'])
            %
            %Now do the same thing, but instead of using the magnitude of
            %the efficiency at each phase (which is at the nominal
            %frequency freqGHz, use the mean of these efficiencies over all
            %frequencies of interest at each phase. These have had to been 
            %computed separately and saved in a file with extension .lum.
            %If such a file is not present, then skip this part.
            [FileName,PathName]=uigetfile('*.lum','Select the lum file');
            if PathName~=0
                magCSTmeanEff=load(FileName);
                meanMagCSTmeanEff=zeros(nPhases,1);
                for ii=1:nPhases
                    phiArb=phsCSTdeg(ii);
                    indLens=xxx(app,y0,nunitsx,phsCSTdeg,phiArb);
                    meanMagCSTmeanEff(ii)=mean(magCSTmeanEff(indLens));
                end
                axes(currentaxis)
                hold on
                plot(phsCSTdeg,100*meanMagCSTmeanEff,'r','LineWidth',2);
                hold off
                fmin=app.minfreqGHzEditField.Value;
                fmax=app.maxfreqGHzEditField.Value;
                legend(['single freq ' num2str(f0) ' GHz'],['mean over freqs [' num2str(fmin) ' ' num2str(fmax) '] GHz'])
            end
            %Place marker on the figure. This marker will be moved as the
            %arbitrary phase angle is changed.
            %Get the axis limits.
            xlim=currentaxis.XLim;
            ylim=currentaxis.YLim;
            app.arbPhaseMarker=line(currentaxis,[xlim(1) xlim(1)],[ylim(1) ylim(2)],...
                'color','k');
        end
        
        function [indLens]=xxx(app,y0,nunitsx,Tphsdeg,phiArb)
            %Given a cylindrical source at x=0, y=f, and a plane lens at y=0
            %which converts the cylindrical wave from the source into a
            %plane wave in the region y<0. The phase of the cylindrical
            %wave is exp(-iwt)*exp(ikr), where k=2pi/lambda and r is the
            %distance from the source. The phase at any point x along y=0
            %is then phi0(x)=k*r=k*sqrt(x*x+f*f). Assume the transmission
            %coefficient across the lens at (x,y=0) is |T(x)|exp(i*phiT(x)).
            %The phase phib(x) along the bottom of the lens will then be
            %phib(x)=phi0(x)+phiT(x). If we want the wave below the lens to
            %be a plane wave, then the phase along the bottom of the lens
            %should be uniform, or always equal to the phase at x=0. Then
            %phib(x)=phib(0), or phi0(x)+phiT(x)=phi0(0)+phiT(0). We
            %therefore define the lens so that the phase difference in
            %radians produced by it at a distance x from its center is
            %phiT(x)=phi0(0)+phiT(0)-phi0(x)=k*f-k*sqrt(x*x+f*f)+phiT(0)
            %or phiT(x)=2pi*[f-sqrt(x*x+f*f)]/lambda+phiT(0) radians, or
            %phiTdeg(x)=360*[f-sqrt(x*x+f*f)]/lambda+phiTdeg(0) degrees.
            %The phiTdeg(0) is the phase of the unit cell that is placed at
            %x=0.  That is, the center cell.  Which cell this is is
            %arbtrary.  We define it to be the cell with transmission phase
            %closest to phiArb. But since there probably will not be a cell
            %with exactly this value, we will use the phiTdeg0 closest to 
            %phiArb. This corresponds to xnd=0 below.
            [~,indexdif]=min(abs(Tphsdeg-(phiArb)));
            phiTdeg0=Tphsdeg(indexdif);
            %
            focDist=y0*app.lambdamm;
            indLens=zeros(1,nunitsx);
            %Assume there is an odd number of unit cells in the lens
            %(nunitsx). %Find the center one.
            nCenter=fix(nunitsx/2)+1;
            indLens(nCenter)=indexdif;
            %for nx=1:nunitsx
            for nx=2:nunitsx
	            %Loop over the unit cells of the lens. 
                %Start from the center and move outwards on both sides.
	            nunitside=fix(nx/2);
	            %We want the first one (nx=1) to be in the middle (nunitside=xnd=0).
	            %The second one, nx=2, nunitside=1 to be to the right (xnd=d),
	            %The thirde one, nx=3, nunitside=1 to be to the left (xnd=-d).
	            if nunitside*2==nx 
		            xnd=nunitside*app.dmm;
                    indTemp=nCenter+nunitside;
                else
		            xnd=-nunitside*app.dmm;
                    indTemp=nCenter-nunitside;
                end
	            %xnd is also the distance from the current unit cell to the center of center unit cell.
	            %Get the added phase for the distance |xnd| from the center of the surface.
	            phaseDeg=-360*(sqrt(xnd*xnd+focDist*focDist)-focDist)/app.lambdamm+phiTdeg0;
	            %If phaseDeg<-180 Then
	            while phaseDeg<-180 
		            %The angle should be between -180 And 180.
		            phaseDeg=phaseDeg+360;
                end
                [~,indexdif]=min(abs(Tphsdeg-phaseDeg));
                indLens(indTemp)=indexdif;
	            %Tphsdeg(indexdif) is the closest to the proper phase angle.
	            %This means the Wk values (in mm) of
	            %WkLUT(indexdif,1),WkLUT(indexdif,2),WkLUT(indexdif,3),WkLUT(indexdif,4),WkLUT(indexdif,5)
	            %are to be used.
            end
            %meanT=mean(Tphsdeg(indLens));
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mainhandles, LUTmaxtimes, LUTminEff, NLUTangles, LUTang1, LUTang2, NLUTmags, LUTtargetTMagnitude, LUTtargetTMagnitude2, frqGHz, dOverLambda, totalThicknessOverLambda)
            % app.LUTmaxtimes=LUTmaxtimes;
            % app.LUTminEff=LUTminEff;
            % app.NLUTangles=NLUTangles;
            % app.LUTang1=LUTang1;
            % app.LUTang2=LUTang2;
            % app.LUTtargetTMagnitude=LUTtargetTMagnitude;
            app.targetmagEditField.Value=LUTtargetTMagnitude;
            app.targetmag2EditField.Value=LUTtargetTMagnitude2;
            app.nomagsSpinner.Value=NLUTmags;
            app.maxtimesSpinner.Value=LUTmaxtimes;
            app.mineffEditField.Value=LUTminEff;
            app.angle1EditField.Value=LUTang1;
            app.noanglesSpinner.Value=NLUTangles;
            app.angle2EditField.Value=LUTang2;
            app.MainApp=mainapp;
            app.MainHandles=mainhandles;
            app.hCartes=-1;  %graphic handle
            app.hh=-1;       %graphic handle
            app.freqGHzEditField.Value=frqGHz;
            c0=299.792458;
            app.lambdamm=c0/frqGHz;
            app.dmm=dOverLambda*app.lambdamm;
            app.periodmmEditField.Value=app.dmm;
            app.lambdaLabel.Text=num2str(app.lambdamm);
            thicknessmm=totalThicknessOverLambda*app.lambdamm;
            app.lensThicknessLabel.Text=num2str(thicknessmm);
            app.unitCellDots=-1;
            app.unitCellCircles=-1;
            app.minfreqGHzEditField.Value=frqGHz-1;
            app.maxfreqGHzEditField.Value=frqGHz+1;
        end

        % Button pushed function: OkButton
        function OkPushbuttonCallback(app, event)
            LUTtargetTMagnitude=app.targetmagEditField.Value;
            LUTtargetTMagnitude2=app.targetmag2EditField.Value;
            NLUTmags=app.nomagsSpinner.Value;
            LUTmaxtimes=app.maxtimesSpinner.Value;
            LUTminEff=app.mineffEditField.Value;
            LUTang1=app.angle1EditField.Value;
            NLUTangles=app.noanglesSpinner.Value;
            LUTang2=app.angle2EditField.Value;
            updateLUT(app.MainApp,LUTmaxtimes,LUTminEff,...
                NLUTangles,LUTang1,LUTang2,...
                NLUTmags,LUTtargetTMagnitude,LUTtargetTMagnitude2)
            
            % Delete the dialog box (or more precisely, the window).
            %Not sure why it is necessary to delete the window.
            %delete(app)
            
        end

        % Callback function: DisplayLUTButton, OpenoptfileMenu
        function displayLUTcallback(app, event)
            %If event is empty, then this is called from the routine for
            %combining files.  Then only allow and combination with an
            %.opte file if the original one was opte.  Same for .opt.
            if isempty(event)
                if strcmp(app.optFileLabel.Text(end),'e')
                    ise=1;
                    [FileName,PathName]=uigetfile('*.opte','Select the data file');
                else
                    ise=0;
                    [FileName,PathName]=uigetfile('*.opt','Select the data file');
                end
            else
                ise=0;
                [FileName,PathName]=uigetfile('*.opt','Select the data file');
            end
            %figure(app.figure1); 
            %Because of bug in Matlab that causes the window to the back.
            if PathName~=0
                app.fileNameTTCST=[];
                app.optFileLabel.Text=FileName;
                readOptFile(app, FileName,ise);
                app.ChoosePointsButton.Enable='on';
                app.AddCSTButton.Enable='on';
                %
                resLength=length(app.reLUT);
                app.ResultNoSpinner.Limits=[1 resLength];
                app.ResultNoSpinner.Value=1;
                %Place marker on this result no. 1. Get axis
                ax=app.h.Parent;
                nconfig=1;
                app.MatlabMark=line(ax,app.reLUT(nconfig),app.imLUT(nconfig),...
                    'Marker','square','MarkerEdgeColor','blue','MarkerSize',16,...
                    'LineWidth',2);
                %
                CSTArraySweepFile(app,FileName,ise)
                %
                %If compatible CST file results exist, read them as well.
                %It is assumed that the name of the opt file was
                %LUTxxxxx.opt  Then the name of the transmission CST file
                %is assumed to be TTxxxxx.txt, and the name of the
                %reflection CST file is assumed to be RRxxxxx.txt. Get the
                %name of TT file.
                optFileNameNoLUT=FileName(4:end);  %Remove LUT from beginning
                CSTFileNameNoTT=replace(optFileNameNoLUT,'.opt','.txt');
                CSTFileName=['TT' CSTFileNameNoTT]; %Place TT at beginning.
                if isfile(CSTFileName)
                    app.fileNameTTCST=CSTFileName;
                    readCSTfile(app, CSTFileName,ise);
                end
            end            
            
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChangedFcn(app, event)
            %selectedButton = app.DisplayButtonGroup.SelectedObject;
            if ishandle(app.h)
                phsLUT=app.phsLUTFields;
                currentAxis=app.h.Parent;
                if app.meanEfficiencyRadioButton.Value
                    %This radio button can only be used when we are
                    %plotting data from an .opte file, not an .opt file.
                    filename=app.optFileLabel.Text;
                    if strcmp(filename(end),'e')
                        magLUT=app.meanEff;
                        %title(currentAxis,'Mean Efficiencies')
                        fmin=app.minfreqGHzEditField.Value;
                        fmax=app.maxfreqGHzEditField.Value;
                        title(currentAxis,...
                            ['Mean Efficiencies for frequencies between ' ...
                            num2str(fmin) ' and ' num2str(fmax) 'GHz'])
                        if ishandle(app.hCartes)
                            app.hCartes(1).YData=magLUT*100;
                        end
                    else
                        msgbox('Mean efficiency chosen but there is no .opte file')
                        return
                    end
                else
                    magLUT=app.magLUTFields;
                    if app.efficiencyRadioButton.Value
                        magLUT=magLUT.*magLUT;
                        title(currentAxis,'Transmitted Efficiencies')
                    else
                        title(currentAxis,'Transmitted Fields')
                    end
                    %If the previously checked radio button was mean
                    %efficiency, then the cartesian figure should be
                    %changed as well.
                    if strcmp(event.OldValue.Text,'mean efficiency')
                        %This must be efficiency even if fields is plotted.
                        if app.efficiencyRadioButton.Value
                            app.hCartes(1).YData=magLUT*100;
                        else
                            app.hCartes(1).YData=sqrt(magLUT)*100;
                        end
                    end
                end
                reLUT1=magLUT.*cosd(phsLUT);
                imLUT1=magLUT.*sind(phsLUT);
                %***********swm added Feb 2025
                app.reLUT=reLUT1;
                app.imLUT=imLUT1;
                %************
                %plot magnitude and phase results
                app.h.XData=reLUT1;
                app.h.YData=imLUT1;
            end
            
            if ishandle(app.hCST)
                phsCST=app.phsCSTFields;
                currentAxis=app.hCST.Parent;
                if app.meanEfficiencyRadioButton.Value
                    magCST=app.meanEffCST;
                else
                    magCST=app.magCSTFields;
                    if app.efficiencyRadioButton.Value
                        magCST=magCST.*magCST;
                        title(currentAxis,'Transmitted Efficiencies')
                    else
                        title(currentAxis,'Transmitted Fields')
                    end
                end
                reCST1=magCST.*cosd(phsCST);
                imCST1=magCST.*sind(phsCST);
                %***********swm added Feb 2025
                app.reCST=reCST1;
                app.imCST=imCST1;
                %************
                %plot magnitude and phase results
                app.hCST.XData=reCST1;
                app.hCST.YData=imCST1;
                %
                app.hCartesCST(1).YData=magCST*100;
            end
            if ishandle(app.h) || ishandle(app.hCST)
                nconfig=app.ResultNoSpinner.Value;
                app.MatlabMark.XData=app.reLUT(nconfig);
                app.MatlabMark.YData=app.imLUT(nconfig);
                if ishandle(app.CSTMark)
                    app.CSTMark.XData=app.reCST(nconfig);
                    app.CSTMark.YData=app.imCST(nconfig);
                end
            end
        end

        % Button pushed function: ChoosePointsButton
        function ChoosePointsButtonCallback(app, event)
            %The following was commented out because it was not being used,
            %and the adding of a marker to the Cartesian Wk figure made it
            %dispensable.
            % %In addition to choosing a point representing a configuration by clicking,
            % %set up the option to display the lengths of Wk for the
            % %configuration over which the cursor is HOVERING. This will be
            % %displayed in a separate plot window.
            % figure
            % figWk=gcf;
            % %Draw the Wk values of the first configuration.
            % nConfig=1;
            % Wk=app.WkLUT(nConfig,:);
            % numPlates=length(Wk);
            % y=1:numPlates;
            % for ii=1:numPlates
            %     hline(ii)=line([-Wk(ii)/2 Wk(ii)/2],[ii ii],'LineWidth',3);
            %     %hline(ii).LineWidth=3;
            % end
            % set(gca,'YLim',[0 numPlates+1])
            % set(gca,'YDir','reverse')
            % set(gca,'XLim',[-60 60])
            % grid
            % 
            % currentAxis=app.h.Parent;
            % axes(currentAxis);  %Make this axis current for ginput
            % currentFigure=currentAxis.Parent;
            % %
            % set (gcf, 'WindowButtonMotionFcn',...
            %     {@mouseMove,app.reLUT,app.imLUT,app.WkLUT,hline,numPlates});
            % %https://www.mathworks.com/matlabcentral/answers/497552-hi-when-you-create-a-plot-and-hover-over-a-data-point-without-pressing-anything-a-datatip-is-displa            
            % function mouseMove (src,event,reLUT,imLUT,WkLUT,hline,numPlates)
            %     C = get (gca, 'CurrentPoint');
            %     x=C(1,1);
            %     y=C(1,2);
            %     distFromPt=(x-reLUT).^2+(y-imLUT).^2;
            %     [~,nConfig]=min(distFromPt);
            %     Wk=WkLUT(nConfig,:);
            %     for ii=1:numPlates
            %         hline(ii).XData=[-Wk(ii)/2 Wk(ii)/2];
            %     end
            % end
            %
            currentAxis=app.h.Parent;
            axes(currentAxis);  %Make this axis current for ginput
            rightClicked=false;
            while ~rightClicked
                [x,y,button]=ginput(1);
                if button==3
                    rightClicked=true;
                else
                    %Get the point closest to click point.
                    if app.displayMatlabCheckBox.Value
                        %Use the Matlab results
                        distFromPt=(x-app.reLUT).^2+(y-app.imLUT).^2;
                    else
                        %Use the CST results
                        distFromPt=(x-app.reCST).^2+(y-app.imCST).^2;
                    end
                    [~,nConfig]=min(distFromPt);
                    Wk=app.WkLUT(nConfig,:);
                    %Send this to the layers program.
                    [app.MainHandles,app.MainApp]=newWk(app.MainApp,app.MainHandles,Wk);
                    %Place the markers in the current locations
                    re=0;
                    im=0;
                    if ishandle(app.MatlabMark)
                        app.MatlabMark.XData=app.reLUT(nConfig);
                        app.MatlabMark.YData=app.imLUT(nConfig);
                        re=app.reLUT(nConfig);
                        im=app.imLUT(nConfig);
                    end
                    if ishandle(app.CSTMark)
                        app.CSTMark.XData=app.reCST(nConfig);
                        app.CSTMark.YData=app.imCST(nConfig);
                        if app.displayCSTCheckBox.Value
                            re=app.reCST(nConfig);
                            im=app.imCST(nConfig);
                        end
                    end
                    app.ResultNoSpinner.Value=nConfig;
                    mag=sqrt(re*re+im*im);
                    phs=atan2d(im,re);
                    app.magValue.Text=num2str(mag,3);
                    app.phsValue.Text=num2str(phs,3);
                    %Reset the marker in the cartes and width figures
                    phsRef=app.LUTresults(nConfig,1);
                    app.cartesMarker.XData=[phsRef phsRef];
                    app.widthMarker.XData=[phsRef phsRef];
                end
            end
            %This is only temporary in order to get the reflection
            %coefficients without rerunning the entire optimization. Here,
            %we send the entire matrix to the Layers program. Hopefully it
            %will result in a file that contains the transmission and
            %reflection coefficients: magnitude and phase.
            %Jan 2025. The need for this has been eliminated because the
            %reflection coefficient has been included in the .opt file
            %(Only the abs value, but that's enough.)
            %newWkLUT(app.MainApp,app.MainHandles,app.WkLUT)

        end

        % Close request function: UIFigure
        function closeFunctionNoUpdate(app, event)
            noUpdateLUT(app.MainApp)
            delete(app)
        end

        % Button pushed function: GoSortButton
        function sortAndFilterCallback(app, event)
            %The entire matrix originally referred to with an .opt suffix
            % is app.LUTresults. The columns of this matrix are as follows:
            % Col. 1: The target phase.
            % Col. 2: The transmitted field magnitude obtained from optimization 
            %           in Matlab (hopefully near 1).
            % Col. 3: The transmitted field phase obtained from optimization 
            %           in Matlab (hopefully near the target phase).
            % Col. 4: The reflected field magnitude obtained from Matlab
            %         (hopefully small).
            % Cols. 5:end The lengths of the plates in mils. This is also
            %              given as app.WkLUT.
            % If the CST results are available (TT for the transmitted
            % results, RR for the reflected results), each row of these
            % must correspond to each row of the app.LUTresults matrix.
            %The CST vectors for transmission are 
            % app.magCSTFields, phsCST=app.phsCSTFields,
            % and for reflection is app.magCSTFieldsReflec.
            
            % This routine first sorts the
            %entire matrix by the phase (-180 to 180). 
            %
            app.deletedPoints=[];
            source=app.LUTresults;
            sizesource=size(source);
            if app.CSTButton.Value
                %The sorting will take place according the CST result for
                %the phase.
                %Make sure CST results are available.
                if isempty(app.fileNameTTCST)
                    msgbox('Cannot sort according to CST results since they do not exist.')
                    return
                end
                %CST results are available. Make sure they are compatible
                %with the Matlab results.
                nrows=sizesource(1);
                nrowsTT=length(app.magCSTFields);
                if nrows~=nrowsTT
                    msgbox('CST results are incompatible with Matlab results.')
                    return
                end
                %Everything's hunky dory. Let's sort.
                A=app.phsCSTFields;
                [B,I]=sort(A);
            else
                A=source(:,1);
                [B,I]=sort(A);
            end
            %Now go through each of the vectors and sort it according to I.
            %This includes the sorted vector B. (B is not used.)
            %First, the vectors in the .opt file.
            ncol=sizesource(2);
            target=[];
            for ii=1:ncol
                Bii=source(:,ii);
                target=[target Bii(I)];
            end
            %target is sorted as increasing phase angle.
            %Let's forget about the rest of this function, and just save
            %the sorted opt file and the resulting CST files.
            oldFileName=app.optFileLabel.Text;
            if strcmp(oldFileName(end),'e')
                newFileName=replace(oldFileName,'.opte','sorted.opte');
                ise=1;
            else
                newFileName=replace(oldFileName,'.opt','sorted.opt');
                ise=0;
            end
            save(newFileName,'target','-ascii')
            %app.optFileLabel.Text=newFileName;
            %readOptFile(app, newFileName);
            %CSTArraySweepFile(app,newFileName); %create a new .wk file.
            %
            %Sort the CST result files.
            %Do they exist?
            if ~isempty(app.fileNameTTCST)
                %okay, a CST filename exists. See if its data size is the same
                %as that of the optfile.
                nrows=sizesource(1);
                nrowsTT=length(app.magCSTFields);
                if nrows==nrowsTT
                    %The size of the CST results file is compatible with
                    %the .opt file. So sort these as well.
                    Bii=app.magCSTFields;
                    magCSTFieldsSorted=Bii(I);
                    Bii=app.phsCSTFields;
                    phsCSTFieldsSorted=Bii(I);
                    if ise
                        Bii=app.meanEffCST;
                        meanEffCSTSorted=Bii(I);
                        CSTFieldsSorted=[magCSTFieldsSorted magCSTFieldsSorted -phsCSTFieldsSorted meanEffCSTSorted];
                    else
                        CSTFieldsSorted=[magCSTFieldsSorted magCSTFieldsSorted -phsCSTFieldsSorted];
                    end
                    %Note: the first column is a dummy column. 
                    %Note: The last column is negative because it emulates
                    %a CST file, and in CST the time dependence is
                    %exp(jwt), and not exp(-iwt) as by us.
                    Bii=app.magCSTFieldsReflec;
                    magCSTFieldsReflecSorted=Bii(I);
                    CSTFieldsSortedReflec=[magCSTFieldsReflecSorted magCSTFieldsReflecSorted -phsCSTFieldsSorted];
                    %Note: the first column is a dummy column. 
                    %The fact that the phase relates to transmission and
                    %not to reflection doesn't matter, since this phase is
                    %not used, and so is also a dummy column.
                    %Now store the sorted results in "sorted" files.
                    oldTTFileName=app.fileNameTTCST;
                    newTTFileName=replace(oldTTFileName,'.txt','sorted.txt');
                    oldRRFileName=replace(app.fileNameTTCST,'TT','RR');
                    newRRFileName=replace(oldRRFileName,'.txt','sorted.txt');
                    save(newTTFileName,'CSTFieldsSorted','-ascii')
                    save(newRRFileName,'CSTFieldsSortedReflec','-ascii')
                    %Now manually close the windows, and re-display the
                    %sorted files.
                end
                if app.CSTButton.Value
                    %Create LUT file for CST. This will contain columns as
                    %follows: 
                    %phsCSTFieldsSorted magCSTFieldsSorted Wk1mm Wk2mm ...
                    %But we want to use this in CST where the phase is the
                    %negative of the phase in Matlab.
                    % -phsCSTFieldsSorted magCSTFieldsSorted Wk1mm Wk2mm ...
                    %But we want the phases in order from small to large:
                    % flipud[-phsCSTFieldsSorted magCSTFieldsSorted Wk1mm Wk2mm
                    % 
                    mil2mm=0.0254;
                    if ise
                        WkLUTmm=target(:,6:end)*mil2mm;
                    else
                        WkLUTmm=target(:,5:end)*mil2mm;
                    end
                    LUTdataForCSTa=[-phsCSTFieldsSorted magCSTFieldsSorted WkLUTmm];
                    CT=flipud(LUTdataForCSTa);
                    if ise
                        LUTfileName=replace(newFileName,'.opte','.lut');
                    else
                        LUTfileName=replace(newFileName,'.opt','.lut');
                    end
                    %save(LUTfileName,'CT','-ascii')
                    %For CST, the data must be comma delimited.
                    %
                    fidOut=fopen(LUTfileName,'w');
                    sizeCT=size(CT);
                    %There are 7 columns. Take this from the layers program
                    %The limits in the following are because one we have already done above. Also,we
                    %don't want to do the last one because that requires a special
                    %format. That's because it can't be a blank line.
                    for ii=1:sizeCT(1)-1
                            tline=[num2str(CT(ii,1)) ',' num2str(CT(ii,2)) ',' num2str(CT(ii,3)) ',' ...
                                num2str(CT(ii,4)) ',' num2str(CT(ii,5)) ',' num2str(CT(ii,6)) ',' num2str(CT(ii,7))];
                            fprintf(fidOut,'%s\r\n',tline);
                    end
                    ii=sizeCT(1);
                    tline=[num2str(CT(ii,1)) ',' num2str(CT(ii,2)) ',' num2str(CT(ii,3)) ',' ...
                        num2str(CT(ii,4)) ',' num2str(CT(ii,5)) ',' num2str(CT(ii,6)) ',' num2str(CT(ii,7))];
                    fprintf(fidOut,'%s',tline);
                    fclose(fidOut);
                    % 
                    %At the same time, we will create LUTB file for Rony at
                    %Satixfaction, which will be the same as the LUT file
                    %except the Wk will be in mil and not mm.
                    if ise
                        WkLUTmil=target(:,6:end);
                    else
                        WkLUTmil=target(:,5:end);
                    end
                   LUTdataForCSTb=[-phsCSTFieldsSorted magCSTFieldsSorted WkLUTmil];
                    CTb=flipud(LUTdataForCSTb);
                    T=array2table(CTb);
                    % T.Properties.VariableNames(1:7) = {'Phase(deg)','|T|','W1(mil)',...
                    %     'W2(mil)','W3(mil)','W4(mil)','W5(mil)'};
                    T.Properties.VariableNames(1:2)={'Phase(deg)','|T|'};
                    for jj=1:app.numWkLayers
                        Wstr=['W' num2str(jj) '(mil)'];   %W1(mil), W2(mil), etc.
                        T.Properties.VariableNames{2+jj}=Wstr;
                    end
                    if ise
                        LUTfileNameb=replace(newFileName,'.opte','.csv');
                    else
                        LUTfileNameb=replace(newFileName,'.opt','.csv');
                    end
                    writetable(T,LUTfileNameb)
                end
            end
            return
            %
            %Get the rows we want to keep. These will be the rows for which
            %the first column is maximum within regions of angular width
            %delang.
            keepRows=[];
            ang1=-179;
            angend=179;
            delang=2;
            angs=ang1:delang:angend;
            lengthangs=length(angs);
            for ii=1:lengthangs-1
                %inda=find(target(:,2)>=angs(ii));
                %indb=find(target(:,2)<angs(ii+1));
                inda=find(target(:,1)>=angs(ii));
                indb=find(target(:,1)<angs(ii+1));
                indc=intersect(inda,indb);
                %[ff,M]=max(target(indc,1));
                [ff,M]=max(target(indc,2));
               keepRows=[keepRows;indc(M)];
            end
            %Finally, in the region [179 181]
            %inda=find(target(:,2)>=-180);
            %indb=find(target(:,2)<ang1);
            inda=find(target(:,1)>=-180);
            indb=find(target(:,1)<ang1);
            indc1=intersect(inda,indb);
            %inda=find(target(:,2)>=angend);
            %indb=find(target(:,2)<180);
            inda=find(target(:,1)>=angend);
            indb=find(target(:,1)<180);
            indc2=intersect(inda,indb);
            indd=[indc1;indc2];
            %[ff,M]=max(target(indd,1));
            [ff,M]=max(target(indd,2));
            keepRows=[keepRows;indd(M)];
            %
            targetMax=target(keepRows,:);
            %
            oldFileName=app.optFileLabel.Text;
            if ise
                newFileName=replace(oldFileName,'.opte','sorted.opte');
            else
                newFileName=replace(oldFileName,'.opt','sorted.opt');
            end
            save(newFileName,'targetMax','-ascii')
            app.optFileLabel.Text=newFileName;
            readOptFile(app, newFileName,ise);
            CSTArraySweepFile(app,newFileName,ise)
            
        end

        % Button pushed function: AddCSTButton
        function addCSTCallback(app, event)
            %Choose the file with CST results.  This should be a .txt file
            %created by CST containing polar coordinate results.
            [FileName,PathName]=uigetfile('*.txt','Select the CST txt file');
            %figure(app.figure1); 
            %Because of bug in Matlab that causes the window to the back.
            if PathName~=0
                app.fileNameTTCST=FileName;
                ise=0;
                if strcmp(app.optFileLabel.Text(end),'e')
                    ise=1;
                end
                readCSTfile(app,FileName,ise);
            end                        
            
        end

        % Button pushed function: DeltaMatlabCSTButton
        function DeltaMatlabCSTCallback(app, event)
            magLUT=app.magLUTFields;
            phsLUT=app.phsLUTFields;
            magLUT=magLUT.*magLUT;   %Only consider efficiencies
            reLUT1=magLUT.*cosd(phsLUT);
            imLUT1=magLUT.*sind(phsLUT);
            %
            magCST=app.magCSTFields;
            phsCST=app.phsCSTFields;
            magCST=magCST.*magCST;   %Only consider efficiencies
            reCST=magCST.*cosd(phsCST);
            imCST=magCST.*sind(phsCST);
            %
            lengthLUT=length(magLUT);
            lengthCST=length(magCST);
            lenvec=min(lengthLUT,lengthCST);
            reLUT1=reLUT1(1:lenvec);
            imLUT1=imLUT1(1:lenvec);
            reCST=reCST(1:lenvec);
            imCST=imCST(1:lenvec);
            %
            figure
            hmc(1)=plot([reLUT1(1) reCST(1)],[imLUT1(1) imCST(1)],'k');
            hold on
            for ii=2:lenvec
                hmc(ii)=plot([reLUT1(ii) reCST(ii)],[imLUT1(ii) imCST(ii)],'k');
            end 
            hold off
            %draw unit circle. Saw this at
            %  https://www.mathworks.com/matlabcentral/answers/98665-how-do-i-plot-a-circle-with-a-given-radius-and-center
            hold on
            r=0.5:0.1:0.9;
            h1=fplot(@(t) sin(t), @(t) cos(t));
            h1.Color='r';
            for ii=1:length(r)
                hr(ii)=fplot(@(t) r(ii)*sin(t), @(t) r(ii)*cos(t));
                hr(ii).Color='b';
            end
            hold off
            xlabel('Re(T_0)')
            ylabel('Im(T_0)')
            grid
            axis('equal')
            title('Transmitted Efficiencies')
            %
            %Now Cartesian plot of magnitude vs. phase
            figure
            hmcCartes(1)=plot([phsLUT(1) phsCST(1)],[magLUT(1)*100 magCST(1)*100],'k');
            hold on
            for ii=2:lenvec
                hmcCartes(ii)=plot([phsLUT(ii) phsCST(ii)],[magLUT(ii)*100 magCST(ii)*100],'k');
            end 
            hold off
            xlabel('Phase (deg)')
            ylabel('Efficiency (%)')
            grid
            title('Matlab-CST Comparison')
            ax=hmcCartes.Parent;
            ax.XLim=[-180 180];
            ax.YLim=[0 100];
            %To flip the x-axis for comparison to Vinay results:
            for ii=1:lenvec
                hmcCartes(ii).XData=-hmcCartes(ii).XData;
            end
            %
            %Finally, plot the actual phases of Matlab and CST as a
            %function of the target phases.
            %phsRef=-180:2:178;
            phsRef=app.phsLUTFields;
            figure
            hmcPhase=plot(phsRef,phsLUT,'b',phsRef,phsCST,'r');
            xlabel('target phase (deg)')
            ylabel('actual phase (deg)')
            grid
            title('Matlab-CST Phase Comparison')
            ax=hmcPhase.Parent;
            ax.XLim=[-180 180];
            ax.YLim=[-180 180];
            legend([app.h,app.hCST],'Matlab','CST')
            %
            %Flip the phases to make it compatible with Vinay's paper.
            hmcPhase(1).XData=-hmcPhase(1).XData;
            hmcPhase(2).XData=-hmcPhase(2).XData;
            hmcPhase(1).YData=-hmcPhase(1).YData;
            hmcPhase(2).YData=-hmcPhase(2).YData;
            %
            
        end

        % Value changed function: displayMatlabCheckBox
        function displayMatlabCheckboxCallback(app, event)
            if ~ishandle(app.h)
                return
            end
            value = app.displayMatlabCheckBox.Value;
            if value
                app.h.Visible='on'; 
            else
                app.h.Visible='off'; 
            end
                
        end

        % Value changed function: displayCSTCheckBox
        function displayCSTCheckboxCallback(app, event)
            if ~ishandle(app.hCST)
                return
            end
            value = app.displayCSTCheckBox.Value;
            if value
                app.hCST.Visible='on'; 
            else
                app.hCST.Visible='off'; 
            end
            
        end

        % Value changed function: ResultNoSpinner
        function ResultNoSpinnerCallback(app, event)
            value = app.ResultNoSpinner.Value;
            app.MatlabMark.XData=app.reLUT(value);
            app.MatlabMark.YData=app.imLUT(value);
            re=app.reLUT(value);
            im=app.imLUT(value);
            if ishandle(app.CSTMark)
                app.CSTMark.XData=app.reCST(value);
                app.CSTMark.YData=app.imCST(value);
                if app.displayCSTCheckBox.Value
                    re=app.reCST(value);
                    im=app.imCST(value);
                end
            end
            mag=sqrt(re*re+im*im);
            phs=atan2d(im,re);
            app.MatlabMarkPhs=phs;
            app.magValue.Text=num2str(mag,3);
            app.phsValue.Text=num2str(phs,3);
            %Reset the marker in the cartes and width figures
            phsRef=app.LUTresults(value,1);
            app.cartesMarker.XData=[phsRef phsRef];
            app.widthMarker.XData=[phsRef phsRef];
            if app.RecalcWkCheckBox.Value
                Wk=app.WkLUT(value,:);
                %Send this to the layers program.
                [app.MainHandles,app.MainApp]=newWk(app.MainApp,app.MainHandles,Wk);
            end
            if app.unitcellsCheckBox.Value
                phsCSTdeg=app.phsCSTFields;
                y0=app.focdistEditField.Value;
                nunitsx=app.NounitcellslensEditField.Value;
                phiArb=phsCSTdeg(value);
                indLens=xxx(app,y0,nunitsx,phsCSTdeg,phiArb);
                unitCellLensx=cosd(phsCSTdeg(indLens));
                unitCellLensy=sind(phsCSTdeg(indLens));
                if ishandle(app.unitCellDots)
                    delete(app.unitCellDots)
                end
                app.unitCellDots=line(app.LUTAxis,unitCellLensx,unitCellLensy,...
                    'LineStyle','none','Marker','.','MarkerSize',25,'color','k');
                %For the scenario of a plane wave normal to the surface
                %being transformed to a focus, indLens is symmetric. That
                %is indLens(1)=indLens(end). If there are values of indLens
                %that repeat themselves beyond the symmetry, then they will
                %repeat themselves within the first half of the elements.
                %Determine if there are such repetitions, and if so, circle
                %that dot.
                lenIndLens=length(indLens);
                %if lenIndLens is odd, include the middle element in our check.
                lenIndLensHalf=fix(lenIndLens/2);
                if 2*lenIndLensHalf~=lenIndLens
                    lenIndLensHalf=lenIndLensHalf+1;
                end
                indLensCheck=indLens(1:lenIndLensHalf);
                %Determine if there are multiple entries in indLensCheck.
                %https://stackoverflow.com/questions/32736560/find-non-unique-elements-of-an-array-in-matlab
                [unqA,~,id]=unique(indLensCheck);
                indLensNonUnique=unqA(histcounts(id,1:max(id))>1);
                unitCellLensx=cosd(phsCSTdeg(indLensNonUnique));
                unitCellLensy=sind(phsCSTdeg(indLensNonUnique));
                if ishandle(app.unitCellCircles)
                    delete(app.unitCellCircles)
                end
                if ~isempty(indLensNonUnique)
                    app.unitCellCircles=line(app.LUTAxis,unitCellLensx,unitCellLensy,...
                        'LineStyle','none','Marker','o','MarkerSize',25,'color','k');
                end
                %
                if ishandle(app.arbPhaseMarker)
                    app.arbPhaseMarker.XData=[phiArb phiArb];
                end
            end
            
        end

        % Button pushed function: DeletepointButton
        function deletePointCallback(app, event)
            %The current point (or line in a matrix) will be removed from
            %the appropriate variables: It is assumed their lengths are the
            %same. 
            %Get the current selected index.
            isCST=~isempty(app.hCST);
            if isCST
                %Its possible that hCST was deleted but is still not empty.
                isCST=ishandle(app.hCST);
            end
            if strcmp(app.optFileLabel.Text(end),'e')
                ise=1;
            else
                ise=0;
            end
            % if isempty(app.hCST)
            %     %if CST results have not yet been read, we can't use this.
            %     msgbox('This option is only available if the CST results have been read.')
            %     return
            % end
            nConfig=app.ResultNoSpinner.Value;
            app.deletedPoints=[app.deletedPoints nConfig];
            %When using deletedPoints, it is important to note that they
            %must be implemented sequentially, starting over each time.
            %That is the location of point n is determined after all the
            %previous points have been implemented. That is, point n after
            %the first implementation is not at the same location as point
            %n after the second implementation.
            %Get current length of the variables
            varlen=length(app.magLUTFields);
            switch nConfig
                case 1
                    %The marker is on the first element.
                    app.LUTresults=app.LUTresults(2:end,:);
                    app.WkLUT=app.WkLUT(2:end,:);
                    app.magLUTFields=app.magLUTFields(2:end);
                    app.phsLUTFields=app.phsLUTFields(2:end);
                    app.imLUT=app.imLUT(2:end);
                    app.reLUT=app.reLUT(2:end);
                    if ise
                        app.meanEff=app.meanEff(2:end);
                    end
                    if isCST
                        app.magCSTFields=app.magCSTFields(2:end);
                        app.phsCSTFields=app.phsCSTFields(2:end);
                        app.magCSTFieldsReflec=app.magCSTFieldsReflec(2:end);
                        app.reCST=app.reCST(2:end);
                        app.imCST=app.imCST(2:end);
                        if ise
                            app.meanEffCST=app.meanEffCST(2:end);
                        end
                    end
                case varlen
                    %The marker is on the last element.
                    app.LUTresults=app.LUTresults(1:end-1,:);
                    app.WkLUT=app.WkLUT(1:end-1,:);
                    app.magLUTFields=app.magLUTFields(1:end-1);
                    app.phsLUTFields=app.phsLUTFields(1:end-1);
                    app.imLUT=app.imLUT(1:end-1);
                    app.reLUT=app.reLUT(1:end-1);
                    if ise
                        app.meanEff=app.meanEff(1:end-1);
                    end
                    if isCST
                        app.magCSTFields=app.magCSTFields(1:end-1);
                        app.phsCSTFields=app.phsCSTFields(1:end-1);
                        app.magCSTFieldsReflec=app.magCSTFieldsReflec(1:end-1);
                        app.reCST=app.reCST(1:end-1);
                        app.imCST=app.imCST(1:end-1);
                        if ise
                            app.meanEffCST=app.meanEffCST(1:end-1);
                        end
                    end
                    %The marker was on the last element which no longer
                    %exists. So place it on what is now the last element.
                    app.ResultNoSpinner.Value=varlen-1;
                otherwise
                    %The marked element is an intermediate element.
                    app.LUTresults=app.LUTresults([1:nConfig-1 nConfig+1:varlen],:);
                    app.WkLUT=app.WkLUT([1:nConfig-1 nConfig+1:varlen],:);
                    app.magLUTFields=app.magLUTFields([1:nConfig-1 nConfig+1:varlen]);
                    app.phsLUTFields=app.phsLUTFields([1:nConfig-1 nConfig+1:varlen]);
                    app.imLUT=app.imLUT([1:nConfig-1 nConfig+1:varlen]);
                    app.reLUT=app.reLUT([1:nConfig-1 nConfig+1:varlen]);
                    if ise
                        app.meanEff=app.meanEff([1:nConfig-1 nConfig+1:varlen]);
                    end
                    if isCST
                        app.magCSTFields=app.magCSTFields([1:nConfig-1 nConfig+1:varlen]);
                        app.phsCSTFields=app.phsCSTFields([1:nConfig-1 nConfig+1:varlen]);
                        app.magCSTFieldsReflec=app.magCSTFieldsReflec([1:nConfig-1 nConfig+1:varlen]);
                        app.reCST=app.reCST([1:nConfig-1 nConfig+1:varlen]);
                        app.imCST=app.imCST([1:nConfig-1 nConfig+1:varlen]);
                        if ise
                            app.meanEffCST=app.meanEffCST([1:nConfig-1 nConfig+1:varlen]);
                        end
                    end
                    %The marker was on the last element which no longer
                    %exists. So place it on what is now the last element.
            end
            %Redisplay the Matlab points.
            app.h.XData=app.reLUT;
            app.h.YData=app.imLUT;
            if ishandle(app.hCST)
                %Redisplay the CST points
                app.hCST.XData=app.reCST;
                app.hCST.YData=app.imCST;
            end
            %Relocate the markers
            event=[];
            ResultNoSpinnerCallback(app, event)
            %Modify the spinner limit
            app.ResultNoSpinner.Limits=[1 varlen-1];

           
        end

        % Button pushed function: savemodifiedfilesButton
        function saveModifiedFilesCallback(app, event)
            %The files that must be saved are the .opt file, the TT.txt
            %file, and the RR.txt file. The name of the TT file is
            %app.fileNameTTCST. The name of the .opt file is located in
            %app.optFileLabel.Text.  The saved files will have the same
            %names as the original, but with _mod at the end of the name
            %before the suffix.
            %First the opt file
            fileName=app.optFileLabel.Text;
            if strcmp(fileName(end),'e')
                ise=1;
                newOptFileName=replace(fileName,'.opte','_mod.opte');
            else
                ise=0;
                newOptFileName=replace(fileName,'.opt','_mod.opt');
            end
            fileContents=app.LUTresults;
            save(newOptFileName,'fileContents','-ascii');
            app.optFileLabel.Text=newOptFileName;
            %
            isCST=~isempty(app.hCST);
            if isCST
                %Its possible that hCST was deleted but is still not empty.
                isCST=ishandle(app.hCST);
            end
            if isCST
                %Now the TT file.
                fileName=app.fileNameTTCST;
                newTTFileName=replace(fileName,'.txt','_mod.txt');
                if ise
                    fileContents=[app.phsCSTFields app.magCSTFields -app.phsCSTFields app.meanEffCST]; %neg sign because exp(jwt)
                else
                    fileContents=[app.phsCSTFields app.magCSTFields -app.phsCSTFields]; %neg sign because exp(jwt)
                end
                save(newTTFileName,'fileContents','-ascii');
                app.fileNameTTCST=newTTFileName;
                %Now the RR file
                newRRFileName=replace(newTTFileName,'TT','RR');
                fileContents=[app.phsCSTFields app.magCSTFieldsReflec -app.phsCSTFields];
                save(newRRFileName,'fileContents','-ascii');
            end
            %
            %Now let's try to save the frequency response file that might
            %be given in the CST results. It is assumed that the LUT
            %display has been modified ONLY BY DELETING POINTS and NOT by
            %combining files. Be sure to keep a backup of the current files
            %which start with TT and RR. These are different from the ones
            %above because the ones above contain CST information for the
            %nominal frequency.  The files we consider now have information
            %for many frequencies.
            %Determine the CST file status. If it exists, determine if is
            %for multiple cases or for only a single case. The following is
            %taken from the frequencyResponse function in the Layers
            %program.
            if isempty(app.deletedPoints)
                return
            end
            CSTresultsfilename=app.CSTresultsfilenameEditField.Value;
            isCST=0;
            if length(CSTresultsfilename)>1
                %A file name has been given. Determine if the file
                %exists for transmission and reflection.
                if isfile(CSTresultsfilename)
                    %Make sure the name starts with TT and there is
                    %also a file that starts with RR.
                    if strcmp('TT',CSTresultsfilename(1:2))
                        CSTresultsfilenameRef=replace(CSTresultsfilename,'TT','RR');
                        if isfile(CSTresultsfilenameRef)
                            isCST=1;
                        else
                            msgbox(['The name of reflection CST results file ' CSTresultsfilenameRef 'does not exist.'])
                            return
                        end
                    else
                        msgbox(['The name of CST results file ' CSTresultsfilename 'does not start with TT.'])
                        return
                    end   
                else
                    msgbox(['CST results file ' CSTresultsfilename 'does not exist.'])
                    return
                end
            end
            if isCST
                isMultiCST=0;  %The CST file contains only a single phase case.
                nFreqsCST=0;
                %Determine whether the CST file contains multiple phases.
                %The file contains the matrix 
                % [frequency S-parameter-magnitude  S-parameter-phase]
                a=importdata(CSTresultsfilename);
                nFreqsCST=length(a.data);
                %nFreqsCST is the number of lines from the first header to
                %the second header if there is one.
                %Here's a Kurdish way of determining whether there are
                %multiple files. Determine if there are more than nFreqsCST
                %lines in the file.
                fid = fopen(CSTresultsfilename,'r');
                n = 0;
                while ~feof(fid) && n<=2*nFreqsCST
                    fgetl(fid);
                    n = n+1;
                end
                fclose(fid);
                if n>2*nFreqsCST
                    isMultiCST=1;
                else
                    %The CST files contains frequency response for only a
                    %single phase. The cannot be used if isMultipleCases is
                    %true.
                    if isMultipleCases
                        msgbox('Multiple cases requested, but CST file contains only a single case.')
                        return
                    end
                    isMultiCST=0;
                end
                %
            else
                isMultiCST=0;
                nFreqsCST=0;
            end
            if isMultiCST==0
                return
            end
            ndeletedPoints=length(app.deletedPoints);
            nphasesStart=length(app.phsLUTFields)+ndeletedPoints;
            %This is the number of phases that there were before we started
            %deleting points.  
            CSTresultsfilenameTemp=replace(CSTresultsfilename,'.txt','Temp.txt');
            for mm=1:2
                %mm=1 is for revising the TT file, %mm=2 for revising the RR file.
                if mm==1
                    sourceFileName=CSTresultsfilename;
                else
                    sourceFileName=CSTresultsfilenameRef;
                end
                nphases=nphasesStart;
                for jj=1:ndeletedPoints
                    phaseNumberToRemove=app.deletedPoints(jj);
                    %We want to remove this set of data from the file. Read
                    %each section and write it to temporary file, except for
                    %section phaseNumberToRemove. 
                    %CSTresultsfilename and keep the same name.
                    %Open source file and temporary file.
                    fid=fopen(sourceFileName,'r');
                    fidTemp=fopen(CSTresultsfilenameTemp,'w');
                    %ii=1 is separated from ii=2:nphases because the
                    %Headerlines value is different.
                    CSTDataCell=textscan(fid,'%f %f %f',nFreqsCST,'Headerlines',3,'CollectOutput',1);
                    if phaseNumberToRemove~=1
                        CSTData=cell2mat(CSTDataCell);
                        %Write the header (or similar) to temporary file.
                        for kk=1:3
                            tline='###';
                            fprintf(fidTemp,'%s\r\n',tline);
                        end
                        %Write the data to temporary file.
                        for kk=1:nFreqsCST
                            fprintf(fidTemp,'%f %f %f \r\n',CSTData(kk,1:3));
                        end
                    end
                    %
                    for ii=2:nphases
                        CSTDataCell=textscan(fid,'%f %f %f',nFreqsCST,'Headerlines',4,'CollectOutput',1);
                        if phaseNumberToRemove~=ii
                            CSTData=cell2mat(CSTDataCell);
                            %Write the header (or similar) to temporary file.
                            for kk=1:3
                                tline='###';
                                fprintf(fidTemp,'%s\r\n',tline);
                            end
                            %Write the data to temporary file.
                            for kk=1:nFreqsCST
                                fprintf(fidTemp,'%f %f %f \r\n',CSTData(kk,1:3));
                            end
                        end
                    end
                    fclose(fid);
                    fclose(fidTemp);
                    movefile(CSTresultsfilenameTemp,sourceFileName)
                    nphases=nphases-1;
                end
            end
            %
            app.deletedPoints=[];            
        end

        % Button pushed function: closeallfiguresButton
        function closeAllFiguresPushbuttonCallback(app, event)
            close all
        end

        % Callback function: CombinewithFilesButton, MergewithMenu
        function CombinewithFilesButtonCallback(app, event)
            combineWithFile(app)
        end

        % Button pushed function: exhGoPhaseFilterPushbutton
        function exhGoPhaseFilterPushbuttonCallback(app, event)
            phaseDiff=app.exhPhaseDiffNumEdit.Value;
            if phaseDiff==0 
                msgbox('phase difference = 0')
                return
            end
            nlargest=app.nLargestSpinner.Value;
            if strcmp(app.optFileLabel.Text(end),'e')
                ise=1;
            else
                ise=0;
            end
            %
            %If CST results are being displayed, perform the filtering
            %according to CST. This means that the results must be sorted
            %in accordance with the CST phase.
            isCST=~isempty(app.hCST);
            if isCST
                %Its possible that hCST was deleted but is still not empty.
                isCST=ishandle(app.hCST);
            end
            
            %Associate a section number with each result starting with the
            %one with phase near -180.
            if isCST
                %app.magCSTFields=app.magCSTFields(2:end);
                phs=app.phsCSTFields;
            else
                phs=app.LUTresults(:,1); 
            end
            %Make sure phs is sorted.
            if ~issorted(phs)
                msgbox('Entries must be sorted. Run Sort-by-phase (target or CST), then redisplay that sorted file.')
                return
            end
            diffs=(phs'-(-180))/phaseDiff;
            ndiffs=1+fix(diffs);
            maxndiffs=1+fix(360/phaseDiff);
            indToRemove=[];
            for ii=1:maxndiffs
                %For each ii, find all the results with ndiffs=ii, among
                %those find the nlargest largest ones (in magnitude), and
                %store all the others for removal.
                indThisPhaseDiff=find(ndiffs==ii);
                if ~isempty(indThisPhaseDiff)
                    if nlargest<length(indThisPhaseDiff)
                        if isCST
                            magsThisPhaseDiff=app.magCSTFields(indThisPhaseDiff);
                        else
                            if ise && app.meanEfficiencyRadioButton.Value
                                magsThisPhaseDiff=app.meanEff(indThisPhaseDiff);
                            else
                                magsThisPhaseDiff=app.magLUTFields(indThisPhaseDiff);
                            end
                        end
                        [magsThisPhaseDiffSorted,I]=sort(magsThisPhaseDiff);
                        %Remove the elements we want to save from indThisPhaseDiff
                        indThisPhaseDiff(I(end-nlargest+1:end))=[];
                        indToRemove=[indToRemove indThisPhaseDiff];
                    end
                end
            end
            %Now remove them one by one.
            lenToRemove=length(indToRemove);
            event=[];
            indToRemoveFlipped=flip(indToRemove);
            for ii=1:lenToRemove
                app.ResultNoSpinner.Value=indToRemoveFlipped(ii);
                deletePointCallback(app, event)
            end
            CartesPlots(app,ise);
        end

        % Button pushed function: exhGoMagnitudeFilterPushbutton
        function exhGoMagnitudeFilterPushbuttonCallback(app, event)
            minMag=app.exhMinMagNumEdit.Value;
            if minMag==0
                msgbox('minimum magnitude = 0')
                return
            end
            maxMag=app.exhMaxMagNumEdit.Value;
            if minMag>maxMag
                msgbox('minimum magnitude > maximum magnitude')
                return
            end
            if strcmp(app.optFileLabel.Text(end),'e')
                ise=1;
            else
                ise=0;
            end
            %phs=app.phsLUTFields;
            phs=app.LUTresults(:,1);  
            %Make sure phs is sorted.
            if ~issorted(phs)
                msgbox('Entries must be sorted. Run Sort-by-phase (target), then redisplay that sorted file.')
                return
            end
            isCST=~isempty(app.hCST);
            if isCST
                %Its possible that hCST was deleted but is still not empty.
                isCST=ishandle(app.hCST);
            end
            if isCST
                mag=app.magCSTFields;
            else
                mag=app.magLUTFields;
            end
            %We cannot delete both the lower and higher at the same time
            %because then the elimination will not be sorted. So first the
            %min.
            indToRemove=find(mag<minMag);
            indToRemoveFlipped=flip(indToRemove);
            %We must go backwards, because only then the removal of a
            %result will not affect the other numbers. This assumes phs is
            %sorted.
            lenToRemove=length(indToRemove);
            event=[];
            for ii=1:lenToRemove
                app.ResultNoSpinner.Value=indToRemoveFlipped(ii);
                deletePointCallback(app, event)
            end
            %
            %And now the max.
            if maxMag<1
                %We want to delete from values from maxMag to 1.
                if isCST
                    mag=app.magCSTFields;
                else
                    mag=app.magLUTFields;
                end
                indToRemove=find(mag>maxMag);
                indToRemoveFlipped=flip(indToRemove);
                %We must go backwards, because only then the removal of a
                %result will not affect the other numbers. This assumes phs is
                %sorted.
                lenToRemove=length(indToRemove);
                event=[];
                for ii=1:lenToRemove
                    jj=indToRemoveFlipped(ii);
                    app.ResultNoSpinner.Value=jj;
                    deletePointCallback(app, event)
                end
            end
            %
            CartesPlots(app,ise);
        end

        % Value changed function: freqGHzEditField
        function freqGHzEditFieldCallback(app, event)
            value = app.freqGHzEditField.Value;
            c0=299.792458;
            app.lambdamm=c0/value;            
        end

        % Menu selected function: OpenpowersourceonlyfileMenu
        function OpenpowersourceonlyfileMenuCallback(app, event)
            [FileName,PathName]=uigetfile('*.txt','Select the power-source-only file');
            if PathName~=0
                app.powersourcefilenameLabel.Text=FileName;
            end
        end

        % Menu selected function: OpenpowerfileMenu
        function OpenpowerfileMenuCallback(app, event)
            [FileName,PathName]=uigetfile('*.txt','Select the power file');
            if PathName~=0
                app.powerfilenameLabel.Text=FileName;
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonCallback(app, event)
            focdist=app.focdistEditField.Value*app.lambdamm;
            lensThickness=str2double(app.lensThicknessLabel.Text);
            %[powerCouplingEffTransmission,powerCouplingEffReflec,powerCouplingEffSideways]=...
            % cst2effpw2(app.lambdamm,app.dmm,app.powersourcefilenameLabel.Text,...
            %     app.powerfilenameLabel.Text,focdist,lensThickness);
            [powerCouplingEffTransmission,powerCouplingEffReflec,powerCouplingEffSideways]=...
            cst2effpw3(app.lambdamm,app.dmm,...
                app.powerfilenameLabel.Text,focdist,lensThickness);
            app.etaTLabel.Text=num2str(powerCouplingEffTransmission*100,3);
            app.etaRLabel.Text=num2str(powerCouplingEffReflec*100,3);
            app.etaSidewaysLabel.Text=num2str(powerCouplingEffSideways*100,3);
            
        end

        % Button pushed function: AnalyzeArbPhaseButton
        function AnalyzeArbPhaseButtonCallback(app, event)
            getArbPhi(app)
        end

        % Button pushed function: freqRespGoButton
        function freqRespGoButton_callback(app, event)
            %Check the input values.
            app.deletedPoints=[];
            freqmin=app.minfreqGHzEditField.Value;
            freqmax=app.maxfreqGHzEditField.Value;
            f0=app.freqGHzEditField.Value;
            % if f0<freqmin || f0>freqmax
            %     msgbox('f0 must be between min freq and max freq.')
            %     return
            % end
            if freqmin>freqmax
                msgbox('min freq > max freq.')
                return
            end
            if app.AlloptfileentriesCheckBox.Value
                optFileName=app.optFileLabel.Text;
                if strcmp(optFileName,'file name')
                    msgbox('.opt file is not defined.')
                    return
                end
                isMultipleCases=1;
            else
                isMultipleCases=0;
            end
            isMeanEffOptim=app.meaneffoptimCheckBox.Value;
            MeanEffOptimWeight=app.Weight01EditField.Value;
            numSamples=app.numberofsamplesEditField.Value;
            EffRef=app.refefficiencyEditField.Value/100;
            CSTresultsfilename=strtrim(app.CSTresultsfilenameEditField.Value);
            upperDistOverLambda0=app.UpperdistEditField.Value;
            lowerDistOverLambda0=app.LowerdistEditField.Value;
            resultNumber=app.ResultNoSpinner.Value;
            [meanEffCSTa,meanEffiency,EffCSTf0,phsCSTf0,EffCSTf0Ref,phsCSTf0Ref]=...
                frequencyResponse(app.MainApp,app.MainHandles,...
                isMultipleCases,f0,freqmin,freqmax,numSamples,...
                app.phsLUTFields,app.WkLUT,EffRef,CSTresultsfilename,...
                upperDistOverLambda0,lowerDistOverLambda0,resultNumber,...
                isMeanEffOptim,MeanEffOptimWeight);
            ise=0;
            if isMultipleCases                 %for the analytical results.
                fileName=app.optFileLabel.Text;
                %If the mean efficiency info is already available in
                %fileName, then don't re-write it.
                if ~strcmp(fileName(end),'e')
                    newOptFileName=replace(fileName,'.opt','.opte');
                    app.meanEff=meanEffiency;
                    %integrate the mean efficiency over frequency, meanEff,
                    %into the .opt file and give it the suffix .opte.
                    LUTresultse=[app.LUTresults(:,1:4) meanEffiency app.WkLUT];
                    save(newOptFileName,'LUTresultse','-ascii');
                    app.LUTresults=LUTresultse;
                    app.optFileLabel.Text=newOptFileName;
                    ise=1;
                else
                    ise=1;
                end
                %The fact is according to the above logic ise=1.  That is,
                %if it wasn't an .opte file to begin with, it would be an
                %.opte file now.
            end
            if isMultipleCases && length(CSTresultsfilename)>1    %CST results
                %The mean efficiency results of the CST calculations should
                %be contained in meanEffCST. But the order of this vector
                %is according to app.phsLUTFields. It is assumed that 
                % app.phsLUTFields is sorted.  But we want the meanEffCST
                % vector to be sorted according to the sorting of app.phsCSTFields,
                %since we will be using it together with the .lut file
                %which is sorted in that manner.
                if isempty(app.fileNameTTCST)
                    %There is no CST file for the CST field at the nominal
                    %frequency f0. But since the more comprehensive file 
                    % CSTresultsfilename is available, we can construct it
                    % from that file and from what has been done above.
                    optFileNameNoLUT=app.optFileLabel.Text(4:end);  %Remove LUT from beginning
                    if ise
                        CSTFileNameNoTT=replace(optFileNameNoLUT,'.opte','.txt');
                    else
                        CSTFileNameNoTT=replace(optFileNameNoLUT,'.opt','.txt');
                    end
                    CSTFileName=['TT' CSTFileNameNoTT]; %Place TT at beginning.
                    app.magCSTFields=sqrt(EffCSTf0);
                    app.phsCSTFields=-phsCSTf0;
                    app.meanEffCST=meanEffCSTa;
                    CSTFileNameContents=[app.phsLUTFields app.magCSTFields phsCSTf0 meanEffCSTa];
                    %The sqrt above is because fields is expected here (to
                    %make it compatible with files formed from CST which
                    %provide S-parameters).
                    save(CSTFileName,'CSTFileNameContents','-ascii')
                    app.fileNameTTCST=CSTFileName;
                    %
                    %Now the reflection file.
                    CSTFileNameRef=['RR' CSTFileNameNoTT]; %Place TT at beginning.
                    app.magCSTFields=sqrt(EffCSTf0);
                    app.phsCSTFields=-phsCSTf0;
                    app.meanEffCST=meanEffCSTa;
                    CSTFileNameContentsRef=[app.phsLUTFields sqrt(EffCSTf0Ref) -phsCSTf0Ref];
                    %The sqrt above is because fields is expected here (to
                    %make it compatible with files formed from CST which
                    %provide S-parameters).
                    save(CSTFileNameRef,'CSTFileNameContentsRef','-ascii')
                    %
                    readCSTfile(app,CSTFileName,ise);                    
                    %msgbox('Cannot sort according to CST results since they do not exist.')
                    %return
                end
                %CST results are available. Make sure they are compatible
                %with the Matlab results.
                nrows=length(app.phsLUTFields);
                nrowsTT=length(app.magCSTFields);
                if nrows~=nrowsTT
                    msgbox('CST results are incompatible with Matlab results.')
                    return
                end
                if nrows~=length(meanEffCSTa)
                    msgbox('CST results are incompatible with the mean efficiency vector.')
                    return
                end
                %Everything's hunky dory. Let's sort.
                A=app.phsCSTFields;
                [B,I]=sort(A);
                meanEffCSTsorted=meanEffCSTa(I);
                save('tempMeanEfficiency.lum','meanEffCSTsorted','-ascii')
            end

        end

        % Button pushed function: EraseunitcellsButton
        function eraseUnitCellsCallback(app, event)
            if ishandle(app.unitCellDots)
                delete(app.unitCellDots)
            end
            if ishandle(app.unitCellCircles)
                delete(app.unitCellCircles)
            end            
        end

        % Menu selected function: OpenoptefileMenu
        function OpenoptefileMenuCallback(app, event)
            [FileName,PathName]=uigetfile('*.opte','Select the data file');
            if PathName~=0
                app.fileNameTTCST=[];
                app.optFileLabel.Text=FileName;
                ise=1;
                app.meanEfficiencyRadioButton.Value=1;
                readOptFile(app, FileName,ise);
                app.ChoosePointsButton.Enable='on';
                app.AddCSTButton.Enable='on';
                %
                resLength=length(app.reLUT);
                app.ResultNoSpinner.Limits=[1 resLength];
                app.ResultNoSpinner.Value=1;
                %Place marker on this result no. 1. Get axis
                ax=app.h.Parent;
                nconfig=1;
                app.MatlabMark=line(ax,app.reLUT(nconfig),app.imLUT(nconfig),...
                    'Marker','square','MarkerEdgeColor','blue','MarkerSize',16,...
                    'LineWidth',2);
                %
                CSTArraySweepFile(app,FileName,ise)
                %
                %If compatible CST file results exist, read them as well.
                %It is assumed that the name of the opt file was
                %LUTxxxxx.opt  Then the name of the transmission CST file
                %is assumed to be TTxxxxx.txt, and the name of the
                %reflection CST file is assumed to be RRxxxxx.txt. Get the
                %name of TT file.
                optFileNameNoLUT=FileName(4:end);  %Remove LUT from beginning
                if ise
                    CSTFileNameNoTT=replace(optFileNameNoLUT,'.opte','.txt');
                else
                    CSTFileNameNoTT=replace(optFileNameNoLUT,'.opt','.txt');
                end
                CSTFileName=['TT' CSTFileNameNoTT]; %Place TT at beginning.
                if isfile(CSTFileName)
                    app.fileNameTTCST=CSTFileName;
                    readCSTfile(app, CSTFileName,ise);
                end
            end            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFunctionNoUpdate, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenoptfileMenu = uimenu(app.FileMenu);
            app.OpenoptfileMenu.MenuSelectedFcn = createCallbackFcn(app, @displayLUTcallback, true);
            app.OpenoptfileMenu.Text = 'Open .opt file';
            app.OpenoptefileMenu = uimenu(app.FileMenu);
            app.OpenoptefileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenoptefileMenuCallback, true);
            app.OpenoptefileMenu.Text = 'Open .opte file';
            app.MergewithMenu = uimenu(app.FileMenu);
            app.MergewithMenu.MenuSelectedFcn = createCallbackFcn(app, @CombinewithFilesButtonCallback, true);
            app.MergewithMenu.Text = 'Merge with';
            app.OpenpowersourceonlyfileMenu = uimenu(app.FileMenu);
            app.OpenpowersourceonlyfileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenpowersourceonlyfileMenuCallback, true);
            app.OpenpowersourceonlyfileMenu.Text = 'Open power source-only file';
            app.OpenpowerfileMenu = uimenu(app.FileMenu);
            app.OpenpowerfileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenpowerfileMenuCallback, true);
            app.OpenpowerfileMenu.Text = 'Open power file.';
            app.maxtimesSpinnerLabel = uilabel(app.UIFigure);
            app.maxtimesSpinnerLabel.Tooltip = {'The maximum number of optimization attempts for each configuration.'};
            app.maxtimesSpinnerLabel.Text = 'maxtimes';
            app.maxtimesSpinner = uispinner(app.UIFigure);
            app.maxtimesSpinner.Limits = [1 Inf];
            app.maxtimesSpinner.Value = 10;
            app.noanglesSpinnerLabel = uilabel(app.UIFigure);
            app.noanglesSpinnerLabel.Tooltip = {'The maximum number of optimization attempts for each configuration.'};
            app.noanglesSpinnerLabel.Text = 'no. angles';
            app.noanglesSpinner = uispinner(app.UIFigure);
            app.noanglesSpinner.Limits = [1 Inf];
            app.noanglesSpinner.Tooltip = {'The number of phase angles for which the optimization will be performed.'};
            app.noanglesSpinner.Value = 30;
            app.angle1EditFieldLabel = uilabel(app.UIFigure);
            app.angle1EditFieldLabel.Text = 'angle1';
            app.angle1EditField = uieditfield(app.UIFigure, 'numeric');
            app.angle1EditField.Tooltip = {'The table will be formed for phase angles between angle1 and angle2 (deg).'};
            app.angle1EditField.Value = -180;
            app.angle2EditFieldLabel = uilabel(app.UIFigure);
            app.angle2EditFieldLabel.Text = 'angle2';
            app.angle2EditField = uieditfield(app.UIFigure, 'numeric');
            app.angle2EditField.Tooltip = {'The table will be formed for phase angles between angle1 and angle2 (deg).'};
            app.angle2EditField.Value = 180;
            app.mineffEditFieldLabel = uilabel(app.UIFigure);
            app.mineffEditFieldLabel.Tooltip = {'The minimujm efficiency to be included in the results. All results with efficiency less than this will not be included.'};
            app.mineffEditFieldLabel.Text = 'min.eff.';
            app.mineffEditField = uieditfield(app.UIFigure, 'numeric');
            app.mineffEditField.Tooltip = {'The table will be formed for phase angles between angle1 and angle2 (deg).'};
            app.mineffEditField.Value = 0.8;
            app.targetmagEditFieldLabel = uilabel(app.UIFigure);
            app.targetmagEditFieldLabel.Tooltip = {'The minimujm efficiency to be included in the results. All results with efficiency less than this will not be included.'};
            app.targetmagEditFieldLabel.Text = 'target mag.';
            app.targetmagEditField = uieditfield(app.UIFigure, 'numeric');
            app.targetmagEditField.Tooltip = {'The target magnitude of the transmission coefficient.'};
            app.targetmagEditField.Value = 1;
            app.LUTparametersLabel = uilabel(app.UIFigure);
            app.LUTparametersLabel.Text = 'LUT parameters';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkPushbuttonCallback, true);
            app.OkButton.Tooltip = {'Transfer these values to the main app and close this window.'};
            app.OkButton.Text = 'Ok';
            app.DisplayButtonGroup = uibuttongroup(app.UIFigure);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChangedFcn, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.fieldRadioButton = uiradiobutton(app.DisplayButtonGroup);
            app.fieldRadioButton.Text = 'field';
            app.efficiencyRadioButton = uiradiobutton(app.DisplayButtonGroup);
            app.efficiencyRadioButton.Text = 'efficiency';
            app.efficiencyRadioButton.Value = true;
            app.DisplayLUTButton = uibutton(app.DisplayButtonGroup, 'push');
            app.DisplayLUTButton.ButtonPushedFcn = createCallbackFcn(app, @displayLUTcallback, true);
            app.DisplayLUTButton.Tooltip = {'Graphically display the results as points in a complex space.'};
            app.DisplayLUTButton.Text = 'Display LUT ';
            app.ChoosePointsButton = uibutton(app.DisplayButtonGroup, 'push');
            app.ChoosePointsButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosePointsButtonCallback, true);
            app.ChoosePointsButton.Enable = 'off';
            app.ChoosePointsButton.Tooltip = {'Choose points on the display for processing.'};
            app.ChoosePointsButton.Text = 'ChoosePoints';
            app.optFileLabel = uilabel(app.DisplayButtonGroup);
            app.optFileLabel.Text = 'file name';
            app.AddCSTButton = uibutton(app.DisplayButtonGroup, 'push');
            app.AddCSTButton.ButtonPushedFcn = createCallbackFcn(app, @addCSTCallback, true);
            app.AddCSTButton.Enable = 'off';
            app.AddCSTButton.Tooltip = {'Add CST results to the current polar figure. It is assumed that thisse results are on a .txt file created by  CST ofr the results in polar coordinates. The results will be added to the exisitng plot with handleapp.h.'};
            app.AddCSTButton.Text = 'Add CST';
            app.DeltaMatlabCSTButton = uibutton(app.DisplayButtonGroup, 'push');
            app.DeltaMatlabCSTButton.ButtonPushedFcn = createCallbackFcn(app, @DeltaMatlabCSTCallback, true);
            app.DeltaMatlabCSTButton.Enable = 'off';
            app.DeltaMatlabCSTButton.Tooltip = {'Plot the differences between the Matlab results and the CST results in terms a line between each .'};
            app.DeltaMatlabCSTButton.Text = 'ΔMatlab/CST';
            app.displayMatlabCheckBox = uicheckbox(app.DisplayButtonGroup);
            app.displayMatlabCheckBox.ValueChangedFcn = createCallbackFcn(app, @displayMatlabCheckboxCallback, true);
            app.displayMatlabCheckBox.Tooltip = {'Display the Matlab results (blue).'};
            app.displayMatlabCheckBox.Text = 'display Matlab';
            app.displayMatlabCheckBox.Value = true;
            app.displayCSTCheckBox = uicheckbox(app.DisplayButtonGroup);
            app.displayCSTCheckBox.ValueChangedFcn = createCallbackFcn(app, @displayCSTCheckboxCallback, true);
            app.displayCSTCheckBox.Tooltip = {'Display the Matlab results (blue).'};
            app.displayCSTCheckBox.Text = 'display CST';
            app.displayCSTCheckBox.Value = true;
            app.ResultNoSpinnerLabel = uilabel(app.DisplayButtonGroup);
            app.ResultNoSpinnerLabel.Text = 'Result No.';
            app.ResultNoSpinner = uispinner(app.DisplayButtonGroup);
            app.ResultNoSpinner.Limits = [1 Inf];
            app.ResultNoSpinner.ValueChangedFcn = createCallbackFcn(app, @ResultNoSpinnerCallback, true);
            app.ResultNoSpinner.Value = 1;
            app.DeletepointButton = uibutton(app.DisplayButtonGroup, 'push');
            app.DeletepointButton.ButtonPushedFcn = createCallbackFcn(app, @deletePointCallback, true);
            app.DeletepointButton.Tooltip = {'Delete the point that is currently selected. '};
            app.DeletepointButton.Text = 'Delete point';
            app.savemodifiedfilesButton = uibutton(app.DisplayButtonGroup, 'push');
            app.savemodifiedfilesButton.ButtonPushedFcn = createCallbackFcn(app, @saveModifiedFilesCallback, true);
            app.savemodifiedfilesButton.Tooltip = {'After deleting points, the new information can be saved in files with the same names as the original files, but with _mod at the end.'};
            app.savemodifiedfilesButton.Text = 'save modified files';
            app.closeallfiguresButton = uibutton(app.DisplayButtonGroup, 'push');
            app.closeallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @closeAllFiguresPushbuttonCallback, true);
            app.closeallfiguresButton.Text = 'close all figures';
            app.CombinewithFilesButton = uibutton(app.DisplayButtonGroup, 'push');
            app.CombinewithFilesButton.ButtonPushedFcn = createCallbackFcn(app, @CombinewithFilesButtonCallback, true);
            app.CombinewithFilesButton.Tooltip = {'Combine the current Matlab-and-CST results with those of another triplet of files.'};
            app.CombinewithFilesButton.Text = 'Combine with Files';
            app.sortbyphaseButtonGroup = uibuttongroup(app.DisplayButtonGroup);
            app.sortbyphaseButtonGroup.Title = 'sort by phase';
            app.targetButton = uiradiobutton(app.sortbyphaseButtonGroup);
            app.targetButton.Tooltip = {'Sort according the target (desired) value of the phase.'};
            app.targetButton.Text = 'target';
            app.targetButton.Value = true;
            app.CSTButton = uiradiobutton(app.sortbyphaseButtonGroup);
            app.CSTButton.Tooltip = {'Sort according the CST(actual) value of the phase.'};
            app.CSTButton.Text = 'CST';
            app.GoSortButton = uibutton(app.sortbyphaseButtonGroup, 'push');
            app.GoSortButton.ButtonPushedFcn = createCallbackFcn(app, @sortAndFilterCallback, true);
            app.GoSortButton.Tooltip = {'Place the points in order of increasing angle. Place the results in files with the same name as the current file, but with "sort" added.'};
            app.GoSortButton.Text = 'Go';
            app.MagLabel = uilabel(app.DisplayButtonGroup);
            app.MagLabel.Text = 'Mag:';
            app.PhsLabel = uilabel(app.DisplayButtonGroup);
            app.PhsLabel.Text = 'Phs:';
            app.magValue = uilabel(app.DisplayButtonGroup);
            app.magValue.Text = '1';
            app.phsValue = uilabel(app.DisplayButtonGroup);
            app.phsValue.Text = '0';
            app.ExhaustivefilterButtonGroup = uibuttongroup(app.DisplayButtonGroup);
            app.ExhaustivefilterButtonGroup.Title = 'Exhaustive filter';
            app.exhGoPhaseFilterPushbutton = uibutton(app.ExhaustivefilterButtonGroup, 'push');
            app.exhGoPhaseFilterPushbutton.ButtonPushedFcn = createCallbackFcn(app, @exhGoPhaseFilterPushbuttonCallback, true);
            app.exhGoPhaseFilterPushbutton.Tooltip = {'Filter exhaustive search results keeping phases every x degrees +/- y degrees tolerance.'};
            app.exhGoPhaseFilterPushbutton.Text = 'Go';
            app.exhGoMagnitudeFilterPushbutton = uibutton(app.ExhaustivefilterButtonGroup, 'push');
            app.exhGoMagnitudeFilterPushbutton.ButtonPushedFcn = createCallbackFcn(app, @exhGoMagnitudeFilterPushbuttonCallback, true);
            app.exhGoMagnitudeFilterPushbutton.Tooltip = {'Filter exhaustive search results keeping magnitudes > x and diluting the points so their distance apart is greater than delta.'};
            app.exhGoMagnitudeFilterPushbutton.Text = 'Go';
            app.EveryLabel = uilabel(app.ExhaustivefilterButtonGroup);
            app.EveryLabel.Text = 'Every';
            app.degsLabel = uilabel(app.ExhaustivefilterButtonGroup);
            app.degsLabel.Text = 'degs,.';
            app.fieldmagLabel = uilabel(app.ExhaustivefilterButtonGroup);
            app.fieldmagLabel.Text = '> field mag. >';
            app.exhPhaseDiffNumEdit = uieditfield(app.ExhaustivefilterButtonGroup, 'numeric');
            app.exhPhaseDiffNumEdit.Tooltip = {'Filter exhaustive search results keeping phases every x degrees +/- y degrees tolerance.'};
            app.exhMinMagNumEdit = uieditfield(app.ExhaustivefilterButtonGroup, 'numeric');
            app.exhMinMagNumEdit.Tooltip = {'Filter exhaustive search results keeping magnitudes > x '};
            app.KeepthelargestLabel = uilabel(app.ExhaustivefilterButtonGroup);
            app.KeepthelargestLabel.Text = '.  Keep the  largest';
            app.nLargestSpinner = uispinner(app.ExhaustivefilterButtonGroup);
            app.nLargestSpinner.Limits = [1 Inf];
            app.nLargestSpinner.Value = 1;
            app.exhMaxMagNumEdit = uieditfield(app.ExhaustivefilterButtonGroup, 'numeric');
            app.exhMaxMagNumEdit.Tooltip = {'Filter exhaustive search results keeping magnitudes < x '};
            app.exhMaxMagNumEdit.Value = 1;
            app.nonsymindexEditFieldLabel = uilabel(app.DisplayButtonGroup);
            app.nonsymindexEditFieldLabel.Text = 'non-sym index';
            app.nonsymindexEditField = uieditfield(app.DisplayButtonGroup, 'numeric');
            app.nonsymindexEditField.AllowEmpty = 'on';
            app.nonsymindexEditField.Tooltip = {'Non-symmetry index is the average difference between the Wk that can be symmetric.. If they were symmetric, the difference would be 0 so the index would be 0.'};
            app.nonsymindexEditField.Value = 1;
            app.RecalcWkCheckBox = uicheckbox(app.DisplayButtonGroup);
            app.RecalcWkCheckBox.Tooltip = {'When the "Result No." is changed, recalculate the Wk in the Layers window, and the resulting fields.'};
            app.RecalcWkCheckBox.Text = 'Recalc Wk';
            app.meanEfficiencyRadioButton = uiradiobutton(app.DisplayButtonGroup);
            app.meanEfficiencyRadioButton.Text = 'mean efficiency';
            app.targetmagEditFieldLabel_2 = uilabel(app.UIFigure);
            app.targetmagEditFieldLabel_2.Tooltip = {'The minimujm efficiency to be included in the results. All results with efficiency less than this will not be included.'};
            app.targetmagEditFieldLabel_2.Text = 'mag2';
            app.targetmag2EditField = uieditfield(app.UIFigure, 'numeric');
            app.targetmag2EditField.Tooltip = {'The target magnitude of the transmission coefficient.'};
            app.targetmag2EditField.Value = 1;
            app.noanglesSpinnerLabel_2 = uilabel(app.UIFigure);
            app.noanglesSpinnerLabel_2.Tooltip = {'The maximum number of optimization attempts for each configuration.'};
            app.noanglesSpinnerLabel_2.Text = 'no.  mags';
            app.nomagsSpinner = uispinner(app.UIFigure);
            app.nomagsSpinner.Limits = [1 Inf];
            app.nomagsSpinner.Tooltip = {'The number of phase angles for which the optimization will be performed.'};
            app.nomagsSpinner.Value = 1;
            app.focdistnumedit = uibuttongroup(app.UIFigure);
            app.focdistnumedit.Title = 'Efficiency Analysis';
            app.freqGHzLabel = uilabel(app.focdistnumedit);
            app.freqGHzLabel.Text = 'freq (GHz)';
            app.freqGHzEditField = uieditfield(app.focdistnumedit, 'numeric');
            app.freqGHzEditField.ValueChangedFcn = createCallbackFcn(app, @freqGHzEditFieldCallback, true);
            app.periodmmEditFieldLabel = uilabel(app.focdistnumedit);
            app.periodmmEditFieldLabel.Text = 'period (mm)';
            app.periodmmEditField = uieditfield(app.focdistnumedit, 'numeric');
            app.lambdaLabel = uilabel(app.focdistnumedit);
            app.lambdaLabel.Text = '21';
            app.mmLabel = uilabel(app.focdistnumedit);
            app.mmLabel.Text = 'λ (mm) =';
            app.Label = uilabel(app.focdistnumedit);
            app.Label.Text = 'λ ';
            app.NounitcellslensEditFieldLabel = uilabel(app.focdistnumedit);
            app.NounitcellslensEditFieldLabel.Text = 'No. unit cells/lens';
            app.NounitcellslensEditField = uieditfield(app.focdistnumedit, 'numeric');
            app.NounitcellslensEditField.Value = 51;
            app.powersourcefilenameLabel = uilabel(app.focdistnumedit);
            app.powersourcefilenameLabel.Tooltip = {'Name of file containing the power due to the source alone, without any scatterer.'};
            app.powersourcefilenameLabel.Text = 'power source filename';
            app.powerfilenameLabel = uilabel(app.focdistnumedit);
            app.powerfilenameLabel.Tooltip = {'The name of the file produced by CST that contains the x and y power components for the desired lens.'};
            app.powerfilenameLabel.Text = 'power file name';
            app.AnalyzeButton = uibutton(app.focdistnumedit, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonCallback, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.thicknessmmLabel = uilabel(app.focdistnumedit);
            app.thicknessmmLabel.Text = 'thickness (mm) =';
            app.lensThicknessLabel = uilabel(app.focdistnumedit);
            app.lensThicknessLabel.Tooltip = {'The total thickness of the lens, including all layers.'};
            app.lensThicknessLabel.Text = 'lensThickness';
            app.Label_3 = uilabel(app.focdistnumedit);
            app.Label_3.Text = 'η =';
            app.TLabel = uilabel(app.focdistnumedit);
            app.TLabel.Text = 'T';
            app.Label_4 = uilabel(app.focdistnumedit);
            app.Label_4.Text = 'η =';
            app.RLabel = uilabel(app.focdistnumedit);
            app.RLabel.Text = 'R';
            app.Label_5 = uilabel(app.focdistnumedit);
            app.Label_5.Text = 'η  =';
            app.Label_6 = uilabel(app.focdistnumedit);
            app.Label_6.Tooltip = {'sideways coupling efficiency'};
            app.Label_6.Text = '↔';
            app.etaTLabel = uilabel(app.focdistnumedit);
            app.etaTLabel.Tooltip = {'transmitted coupling efficiency'};
            app.etaTLabel.Text = '0';
            app.etaRLabel = uilabel(app.focdistnumedit);
            app.etaRLabel.Tooltip = {'reflected coupling efficiency'};
            app.etaRLabel.Text = '0';
            app.etaSidewaysLabel = uilabel(app.focdistnumedit);
            app.etaSidewaysLabel.Tooltip = {'sideways coupling efficiency.'};
            app.etaSidewaysLabel.Text = '0';
            app.optimumarbphaseLabel = uilabel(app.focdistnumedit);
            app.optimumarbphaseLabel.Text = 'optimum arb. phase =';
            app.producesmeaneffLabel = uilabel(app.focdistnumedit);
            app.producesmeaneffLabel.Text = 'produces mean eff.';
            app.optarbphase = uilabel(app.focdistnumedit);
            app.optarbphase.Tooltip = {'The arbitrary phase (deg) that produces the largest mean efficiency  over all cells in the lens.'};
            app.optarbphase.Text = '0';
            app.optMeanEff = uilabel(app.focdistnumedit);
            app.optMeanEff.Tooltip = {'Optimum mean efficiency (%) over all cells in the lens.'};
            app.optMeanEff.Text = '0';
            app.AnalyzeArbPhaseButton = uibutton(app.focdistnumedit, 'push');
            app.AnalyzeArbPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeArbPhaseButtonCallback, true);
            app.AnalyzeArbPhaseButton.Text = 'Analyze';
            app.Label_2 = uilabel(app.focdistnumedit);
            app.Label_2.Text = 'foc dist';
            app.focdistEditField = uieditfield(app.focdistnumedit, 'numeric');
            app.focdistEditField.Value = 3;
            app.unitcellsCheckBox = uicheckbox(app.focdistnumedit);
            app.unitcellsCheckBox.Tooltip = {'When the "Result No." is changed in the Dispaly Panel, indicate the unit cells that participate in the lens in the LUT figure.'};
            app.unitcellsCheckBox.Text = 'unit cells';
            app.EraseunitcellsButton = uibutton(app.focdistnumedit, 'push');
            app.EraseunitcellsButton.ButtonPushedFcn = createCallbackFcn(app, @eraseUnitCellsCallback, true);
            app.EraseunitcellsButton.Tooltip = {'Erase the display of the unit cells tof the lens that have been drawn on the LUT figure.'};
            app.EraseunitcellsButton.Text = 'Erase unit cells';
            app.frequencyRespnseButtonGroup = uibuttongroup(app.UIFigure);
            app.frequencyRespnseButtonGroup.Title = 'Frequency Response';
            app.minfreqGHzEditFieldLabel = uilabel(app.frequencyRespnseButtonGroup);
            app.minfreqGHzEditFieldLabel.Text = 'min freq (GHz)';
            app.minfreqGHzEditField = uieditfield(app.frequencyRespnseButtonGroup, 'numeric');
            app.minfreqGHzEditField.Tooltip = {'The transmission coupling efficiency will be evaluated for frequencies for min freq to max freq for num samples cases/'};
            app.minfreqGHzEditField.Value = 10;
            app.maxfreqGHzEditFieldLabel = uilabel(app.frequencyRespnseButtonGroup);
            app.maxfreqGHzEditFieldLabel.Text = 'max freq (GHz)';
            app.maxfreqGHzEditField = uieditfield(app.frequencyRespnseButtonGroup, 'numeric');
            app.maxfreqGHzEditField.Tooltip = {'The transmission coupling efficiency will be evaluated for frequencies for min freq to max freq for num samples cases/'};
            app.maxfreqGHzEditField.Value = 18;
            app.refefficiencyLabel = uilabel(app.frequencyRespnseButtonGroup);
            app.refefficiencyLabel.Text = 'ref. efficiency (%)';
            app.refefficiencyEditField = uieditfield(app.frequencyRespnseButtonGroup, 'numeric');
            app.refefficiencyEditField.Tooltip = {'The bandwidth will be determined by the span of frequencies for which the efficiency exceeds this nmber.'};
            app.refefficiencyEditField.Value = 60;
            app.numberofsamplesEditFieldLabel = uilabel(app.frequencyRespnseButtonGroup);
            app.numberofsamplesEditFieldLabel.Text = 'number of samples';
            app.numberofsamplesEditField = uieditfield(app.frequencyRespnseButtonGroup, 'numeric');
            app.numberofsamplesEditField.Tooltip = {'The results are based on the values of the coupling efficiencies for frequencies between min freq and max freq. This is the number of frequency samples used between these min and max frequencies.'};
            app.numberofsamplesEditField.Value = 100;
            app.AlloptfileentriesCheckBox = uicheckbox(app.frequencyRespnseButtonGroup);
            app.AlloptfileentriesCheckBox.Tooltip = {'When checked, perform the frequency response calculations for all cases in the current opt tile. Otherwise it performs a calculation for a single case.  When multiple cases, the bandwidths are saved, '};
            app.AlloptfileentriesCheckBox.Text = 'All opt file entries';
            app.freqRespGoButton = uibutton(app.frequencyRespnseButtonGroup, 'push');
            app.freqRespGoButton.ButtonPushedFcn = createCallbackFcn(app, @freqRespGoButton_callback, true);
            app.freqRespGoButton.Text = 'Go';
            app.CSTResultsPanel = uipanel(app.frequencyRespnseButtonGroup);
            app.CSTResultsPanel.Title = 'CST Results';
            app.CSTresultsfilenameEditField = uieditfield(app.CSTResultsPanel, 'text');
            app.CSTresultsfilenameEditField.Tooltip = {'If frequency response results have been obtianed from CST, the name of the file that contains the transmission coefficient (magnitude and phase) as a function of frequency.. This name should start with TT (transmission), with another file present starting with RR for the reflection coefficient. If these files are not available, the entry should be blank. This filename is only used if the "All opt file entries" checkbox is NOT checked. '};
            app.UpperdistEditFieldLabel = uilabel(app.CSTResultsPanel);
            app.UpperdistEditFieldLabel.Text = 'Upper dist ';
            app.UpperdistEditField = uieditfield(app.CSTResultsPanel, 'numeric');
            app.UpperdistEditField.Value = 3;
            app.Label_7 = uilabel(app.CSTResultsPanel);
            app.Label_7.Text = 'λ ';
            app.Label_8 = uilabel(app.CSTResultsPanel);
            app.Label_8.Text = 'λ ';
            app.LowerdistEditFieldLabel = uilabel(app.CSTResultsPanel);
            app.LowerdistEditFieldLabel.Text = 'Lower dist ';
            app.LowerdistEditField = uieditfield(app.CSTResultsPanel, 'numeric');
            app.LowerdistEditField.Value = 3;
            app.CSTresultsfilenameLabel = uilabel(app.CSTResultsPanel);
            app.CSTresultsfilenameLabel.Text = 'CST results file name';
            app.meaneffoptimCheckBox = uicheckbox(app.frequencyRespnseButtonGroup);
            app.meaneffoptimCheckBox.Tooltip = {'Starting with the current configuration in the Layers window, optimize the Wk to provide optimum mean efficiency over the indicated frequencies. Weight will be given to the current complex efficiency at the nominal frequency, and to the mean efficiency over all frequencies.'};
            app.meaneffoptimCheckBox.Text = 'mean eff. optim';
            app.Weight01EditFieldLabel = uilabel(app.frequencyRespnseButtonGroup);
            app.Weight01EditFieldLabel.Text = 'Weight 0-1';
            app.Weight01EditField = uieditfield(app.frequencyRespnseButtonGroup, 'numeric');
            app.Weight01EditField.Limits = [0 1];
            app.Weight01EditField.Tooltip = {'The optimization will give weight to both the transmitting efficiency value at the nominal frequency, and the mean efficiency over all frequencies. This is the weight for the latter. The weight for the former will be 1 minus  this weight.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LUTapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end